classdef sie_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        H7136SustainabilityinengineeringappUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ControlsLabel                   matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ElectricityTab                  matlab.ui.container.Tab
        GridmakeupLabel                 matlab.ui.control.Label
        ManualPanel                     matlab.ui.container.Panel
        manual_setbutton                matlab.ui.control.Button
        man_nuclear                     matlab.ui.control.NumericEditField
        man_coal                        matlab.ui.control.NumericEditField
        man_wind                        matlab.ui.control.NumericEditField
        man_solar                       matlab.ui.control.NumericEditField
        man_gas                         matlab.ui.control.NumericEditField
        TopupdemandButtonGroup          matlab.ui.container.ButtonGroup
        CoalButton                      matlab.ui.control.RadioButton
        GasButton                       matlab.ui.control.RadioButton
        WindLabel_7                     matlab.ui.control.Label
        SeasonDropDown                  matlab.ui.control.DropDown
        SeasonDropDownLabel             matlab.ui.control.Label
        GridMakeupLabel                 matlab.ui.control.Label
        EnergyMixGas                    matlab.ui.control.Slider
        WindLabel_6                     matlab.ui.control.Label
        EnergyMixCoal                   matlab.ui.control.Slider
        EnergyMixWind                   matlab.ui.control.Slider
        EnergyMixNuclear                matlab.ui.control.Slider
        WindLabel_5                     matlab.ui.control.Label
        EnergyMixSolar                  matlab.ui.control.Slider
        WindLabel_2                     matlab.ui.control.Label
        WindLabel                       matlab.ui.control.Label
        GridPlot                        matlab.ui.control.UIAxes
        MachinesTab                     matlab.ui.container.Tab
        MachinesListBox                 matlab.ui.control.ListBox
        GascompositionPanel             matlab.ui.container.Panel
        HydrogentypeButtonGroup         matlab.ui.container.ButtonGroup
        GreyButton                      matlab.ui.control.RadioButton
        BlueButton                      matlab.ui.control.RadioButton
        GreenButton                     matlab.ui.control.RadioButton
        WindLabel_10                    matlab.ui.control.Label
        GasComposition                  matlab.ui.control.Slider
        WindLabel_9                     matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        UpdateIndividualMachineButton   matlab.ui.control.Button
        machine_name_label              matlab.ui.control.Label
        EditindividualmachineLabel      matlab.ui.control.Label
        GasConsumptionMJhEditField      matlab.ui.control.NumericEditField
        GasConsumptionMJhEditFieldLabel  matlab.ui.control.Label
        ElectricityConsumptionMJhEditField  matlab.ui.control.NumericEditField
        ElectricityConsumptionMJhEditFieldLabel  matlab.ui.control.Label
        ProcessRatetonhEditField        matlab.ui.control.NumericEditField
        ProcessRatetonhEditFieldLabel   matlab.ui.control.Label
        PurchaseCostEditField           matlab.ui.control.NumericEditField
        PurchaseCostLabel               matlab.ui.control.Label
        emissionmultiplierEditField     matlab.ui.control.NumericEditField
        emissionefficiencyLabel         matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        MachineQuantityEditField        matlab.ui.control.NumericEditField
        MachineQuantityLabel            matlab.ui.control.Label
        MachineSupplierSpinner          matlab.ui.control.Spinner
        MachineSupplierSpinnerLabel     matlab.ui.control.Label
        machine_EditmodeButtonGroup     matlab.ui.container.ButtonGroup
        machine_all_btn                 matlab.ui.control.RadioButton
        machine_ind_btn                 matlab.ui.control.RadioButton
        FindButton                      matlab.ui.control.Button
        SelectedmachineLabel            matlab.ui.control.Label
        UIAxes_machines                 matlab.ui.control.UIAxes
        TransportTab                    matlab.ui.container.Tab
        Label_8                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        processloadmultiplierEditField  matlab.ui.control.NumericEditField
        CompleteprocessloadmultiplierEditFieldLabel  matlab.ui.control.Label
        Image_car                       matlab.ui.control.Image
        Image_van                       matlab.ui.control.Image
        Image_hgv                       matlab.ui.control.Image
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        CapacitytonsLabel               matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        TransportmapLabel               matlab.ui.control.Label
        SelectedtripLabel               matlab.ui.control.Label
        transport_EditmodeButtonGroup   matlab.ui.container.ButtonGroup
        trans_all_btn                   matlab.ui.control.RadioButton
        trans_ind_btn                   matlab.ui.control.RadioButton
        kmLabel_4                       matlab.ui.control.Label
        kmLabel_3                       matlab.ui.control.Label
        kmLabel_2                       matlab.ui.control.Label
        kmLabel                         matlab.ui.control.Label
        dist_fact_shop                  matlab.ui.control.NumericEditField
        dist_stra_fact                  matlab.ui.control.NumericEditField
        dist_beet_fact                  matlab.ui.control.NumericEditField
        dist_cow_fact                   matlab.ui.control.NumericEditField
        ShopLabel                       matlab.ui.control.Label
        FactoryLabel                    matlab.ui.control.Label
        StrawberryfarmLabel             matlab.ui.control.Label
        SugarBeetfarmLabel              matlab.ui.control.Label
        DairyfarmLabel                  matlab.ui.control.Label
        TransportListBox                matlab.ui.control.ListBox
        SizeListBox                     matlab.ui.control.ListBox
        SizeListBoxLabel                matlab.ui.control.Label
        TypeListBox                     matlab.ui.control.ListBox
        TypeListBoxLabel                matlab.ui.control.Label
        FuelListBox                     matlab.ui.control.ListBox
        FuelListBoxLabel                matlab.ui.control.Label
        DistancekmEditField             matlab.ui.control.NumericEditField
        DistancekmEditFieldLabel        matlab.ui.control.Label
        TimingTab                       matlab.ui.container.Tab
        BETALabel_2                     matlab.ui.control.Label
        Processstarttimes24hrLabel      matlab.ui.control.Label
        JamSlider                       matlab.ui.control.Slider
        JamSliderLabel                  matlab.ui.control.Label
        FlavouredyoghurtSlider          matlab.ui.control.Slider
        FlavouredyoghurtSlider_6Label   matlab.ui.control.Label
        StrawberriesSlider              matlab.ui.control.Slider
        StrawberriesSliderLabel         matlab.ui.control.Label
        SugarSlider                     matlab.ui.control.Slider
        SugarSliderLabel                matlab.ui.control.Label
        SugarbeetSlider                 matlab.ui.control.Slider
        SugarbeetSliderLabel            matlab.ui.control.Label
        YoghurtSlider                   matlab.ui.control.Slider
        YoghurtSliderLabel              matlab.ui.control.Label
        RawmilkSlider                   matlab.ui.control.Slider
        RawmilkSliderLabel              matlab.ui.control.Label
        MiscTab                         matlab.ui.container.Tab
        final_product_Slider            matlab.ui.control.Slider
        FinalproductweighttonnesLabel   matlab.ui.control.Label
        final_product_numericalEditField_2  matlab.ui.control.NumericEditField
        CenterPanel                     matlab.ui.container.Panel
        ResultsLabel                    matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        SummaryTab                      matlab.ui.container.Tab
        Label2                          matlab.ui.control.Label
        fig12                           matlab.ui.control.UIAxes
        fig11                           matlab.ui.control.UIAxes
        fig10                           matlab.ui.control.UIAxes
        fig9                            matlab.ui.control.UIAxes
        fig8                            matlab.ui.control.UIAxes
        EnergyExergybyprocessTab        matlab.ui.container.Tab
        BLUEisthesimulationLabel_2      matlab.ui.control.Label
        BLUEisthesimulationLabel        matlab.ui.control.Label
        ProcessLabel                    matlab.ui.control.Label
        ProcessDropDown                 matlab.ui.control.ListBox
        UIAxes_legend1                  matlab.ui.control.UIAxes
        UIAxes_co2                      matlab.ui.control.UIAxes
        UIAxes_energy                   matlab.ui.control.UIAxes
        UIAxes_exergy                   matlab.ui.control.UIAxes
        TransportTab_2                  matlab.ui.container.Tab
        Fuelconsumption_total_EditField  matlab.ui.control.NumericEditField
        FuelconsumptionEditField_2Label  matlab.ui.control.Label
        Numberofjourneys_total_EditField  matlab.ui.control.NumericEditField
        NumberofjourneysEditField_2Label  matlab.ui.control.Label
        FuelconsumptionEditField        matlab.ui.control.NumericEditField
        FuelconsumptionEditFieldLabel   matlab.ui.control.Label
        NumberofjourneysEditField       matlab.ui.control.NumericEditField
        JourneysLabel                   matlab.ui.control.Label
        TotalsLabel                     matlab.ui.control.Label
        SelectedtripLabel_2             matlab.ui.control.Label
        BLUEisthesimulationLabel_3      matlab.ui.control.Label
        BLUEisthesimulationLabel_4      matlab.ui.control.Label
        UIAxes_legend1_2                matlab.ui.control.UIAxes
        UIAxes_t_CExC_2                 matlab.ui.control.UIAxes
        UIAxes_t_CEnC_2                 matlab.ui.control.UIAxes
        UIAxes_t_CO2_2                  matlab.ui.control.UIAxes
        UIAxes_t_load_2                 matlab.ui.control.UIAxes
        UIAxes_t_CExC                   matlab.ui.control.UIAxes
        UIAxes_t_CEnC                   matlab.ui.control.UIAxes
        UIAxes_t_CO2                    matlab.ui.control.UIAxes
        UIAxes_t_load                   matlab.ui.control.UIAxes
        ProcessdiagramsTab              matlab.ui.container.Tab
        ProcessListBox                  matlab.ui.control.ListBox
        FY_9                            matlab.ui.control.Label
        FY_8                            matlab.ui.control.Label
        FY_7x                           matlab.ui.control.Label
        proc_FY_packaging               matlab.ui.control.Label
        FY_5                            matlab.ui.control.Label
        FY_4                            matlab.ui.control.Label
        proc_FY_making                  matlab.ui.control.Label
        FY_2                            matlab.ui.control.Label
        FY_1                            matlab.ui.control.Label
        M_6x                            matlab.ui.control.Label
        M_5                             matlab.ui.control.Label
        M_4                             matlab.ui.control.Label
        proc_M_production               matlab.ui.control.Label
        M_2                             matlab.ui.control.Label
        M_1                             matlab.ui.control.Label
        SB_16                           matlab.ui.control.Label
        SB_15                           matlab.ui.control.Label
        SB_14                           matlab.ui.control.Label
        SB_13                           matlab.ui.control.Label
        proc_SB_clean_sort              matlab.ui.control.Label
        SB_11                           matlab.ui.control.Label
        SB_10                           matlab.ui.control.Label
        SB_9                            matlab.ui.control.Label
        proc_S_recycling                matlab.ui.control.Label
        SB_7                            matlab.ui.control.Label
        proc_SB_production              matlab.ui.control.Label
        SB_5x                           matlab.ui.control.Label
        SB_4                            matlab.ui.control.Label
        SB_3                            matlab.ui.control.Label
        SB_2                            matlab.ui.control.Label
        SB_1                            matlab.ui.control.Label
        SJ_8                            matlab.ui.control.Label
        SJ_7                            matlab.ui.control.Label
        SJ_6                            matlab.ui.control.Label
        SJ_5                            matlab.ui.control.Label
        proc_SJ_jam_making              matlab.ui.control.Label
        SJ_3                            matlab.ui.control.Label
        SJ_2                            matlab.ui.control.Label
        SJ_1                            matlab.ui.control.Label
        S_22                            matlab.ui.control.Label
        S_21                            matlab.ui.control.Label
        S_20                            matlab.ui.control.Label
        proc_S_packaging                matlab.ui.control.Label
        S_18                            matlab.ui.control.Label
        proc_S_sugar_production         matlab.ui.control.Label
        S_16                            matlab.ui.control.Label
        S_15                            matlab.ui.control.Label
        S_14                            matlab.ui.control.Label
        proc_SB_recycling               matlab.ui.control.Label
        S_12                            matlab.ui.control.Label
        S_11                            matlab.ui.control.Label
        S_10                            matlab.ui.control.Label
        proc_S_cleaning_sorting         matlab.ui.control.Label
        S_8                             matlab.ui.control.Label
        S_7                             matlab.ui.control.Label
        S_6                             matlab.ui.control.Label
        proc_S_sugar_beet_production    matlab.ui.control.Label
        S_4                             matlab.ui.control.Label
        S_3                             matlab.ui.control.Label
        S_2                             matlab.ui.control.Label
        S_1                             matlab.ui.control.Label
        Y_16                            matlab.ui.control.Label
        Y_15                            matlab.ui.control.Label
        Y_14                            matlab.ui.control.Label
        Y_13                            matlab.ui.control.Label
        proc_Y_microbial                matlab.ui.control.Label
        Y_11                            matlab.ui.control.Label
        proc_Y_production               matlab.ui.control.Label
        Y_9                             matlab.ui.control.Label
        Y_8                             matlab.ui.control.Label
        proc_Y_spray_drying             matlab.ui.control.Label
        Y_6                             matlab.ui.control.Label
        Y_5                             matlab.ui.control.Label
        Y_4                             matlab.ui.control.Label
        proc_Y_fat_separation           matlab.ui.control.Label
        Y_2                             matlab.ui.control.Label
        Y_1                             matlab.ui.control.Label
        PDimage                         matlab.ui.control.Image
        PDAxes                          matlab.ui.control.UIAxes
        ProcesstimingTab_2              matlab.ui.container.Tab
        BETALabel                       matlab.ui.control.Label
        TimingAxes                      matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        score_MachinecostEditField      matlab.ui.control.NumericEditField
        MachinecostLabel                matlab.ui.control.Label
        score_CDPEditField              matlab.ui.control.NumericEditField
        CDPLabel                        matlab.ui.control.Label
        OUTPUTSCOREButton               matlab.ui.control.Button
        score_CO2e_hold                 matlab.ui.control.Label
        score_machine_hold              matlab.ui.control.Label
        score_CDP_hold                  matlab.ui.control.Label
        score_CO2eEditField             matlab.ui.control.NumericEditField
        CO2ekgLabel                     matlab.ui.control.Label
        HOLDButton                      matlab.ui.control.Button
        ResetHOLDButton                 matlab.ui.control.Button
        ResetProcesstimingButton        matlab.ui.control.Button
        ResetFinalProductButton         matlab.ui.control.Button
        ResetElectricityButton          matlab.ui.control.Button
        ResetMachinesButton             matlab.ui.control.Button
        ResetTransportButton            matlab.ui.control.Button
        V16Label                        matlab.ui.control.Label
        DifficultyButtonGroup           matlab.ui.container.ButtonGroup
        Level3Button                    matlab.ui.control.RadioButton
        Level2Button                    matlab.ui.control.RadioButton
        Level1Button                    matlab.ui.control.RadioButton
        EXITButton                      matlab.ui.control.Button
        Image                           matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        transport_results % result from original transport report
        transport_results_hold % for comparsion
        transport_results_original %for resetting
        report % full exergy/enegy report
        report_hold % held report for comparison
        report_original %for resetting
        fns %fieldnames to populate drop down menu
        machine_database = load('data\machine_database.mat','machine_database'); % current (edited) machine database
        machine_database_original % original machine database for resetting values
        chemical_database = load('data\chemical_database.mat','chemical_database');
        Transport = load('data\Transport.mat','Transport'); %transport database
        Transport_original; % original transport database for resetting values
        % Energy mix data from 2021 UK grid
        % [Gas, Wind, Coal, Nuclear, Solar]
        energy_mix = [41,29,6,18,6];
        do_not_run_program = 0;
        Grid_Composition;
        %Machine coefciccient multipliers
        machine_coeff_low = 0.5;
        machine_coeff_high = 4;
        multiplier_ProcessRate = 1;
        multiplier_EnergyConsumption = 1;
        multiplier_GasConsumption = 1;
        multiplier_CO2e = 1;
        multiplier_PurchaseCost = 1;
        misc; %miscellaneous reporting from sie_app_main
        FC_data; %flowchart data for specifi items from sie_app_main
        transport_totals;
        transport_totals_hold;
        process_timing_results;
        score;
    end

    methods (Access = private)

        function run(app)
            %% run sie_app_main with standard data to populate graphs initially
            %% remember to change the initialisation in startupFcn if you edit this!
            if app.do_not_run_program == 0 %%check if app is pausing to avoid multiple runs
                [app.transport_totals, app.transport_results, app.report, app.machine_database, app.Grid_Composition, app.FC_data, app.process_timing_results] =...
                    sie_app_main(app.energy_mix,app.machine_database,app.chemical_database,app.Transport,app.misc); %+candidate number, write script run sie_app_main & give results
                draw_bar_graphs(app);
                draw_transport_results(app);
                draw_process_diagram(app);
                draw_grid_composition(app);
                draw_process_timings(app);
                draw_summary(app);
                calculate_score(app);
            end
        end

        function  draw_bar_graphs(app)
            %draw energy bar graph
            selected_process = app.ProcessDropDown.Value;
            Y1 = [ app.report.(selected_process).Input.CEnC app.report_hold.(selected_process).Input.CEnC];
            Y2 = [ app.report.(selected_process).Main.CEnC app.report_hold.(selected_process).Main.CEnC ];
            Y3 = [ app.report.(selected_process).Byproduct.CEnC app.report_hold.(selected_process).Byproduct.CEnC ];
            Y4 = [ app.report.(selected_process).Work.CEnC app.report_hold.(selected_process).Work.CEnC ];
            Y = [Y1 ; Y2 ; Y3 ; Y4];
            X = categorical({'Input','Main','Byproduct','Work'});
            X = reordercats(X,{'Input','Main','Byproduct','Work'});
            bar(app.UIAxes_energy,X,Y);
            %draw exergy bar graph
            Y1 = [ app.report.(selected_process).Input.CExC app.report_hold.(selected_process).Input.CExC ];
            Y2 = [ app.report.(selected_process).Main.CExC app.report_hold.(selected_process).Main.CExC ];
            Y3 = [ app.report.(selected_process).Byproduct.CExC app.report_hold.(selected_process).Byproduct.CExC ];
            Y4 = [ app.report.(selected_process).Work.CExC app.report_hold.(selected_process).Work.CExC ];
            Y = [Y1 ; Y2 ; Y3 ; Y4];
            X = categorical({'Input','Main','Byproduct','Work'});
            X = reordercats(X,{'Input','Main','Byproduct','Work'});
            bar(app.UIAxes_exergy,X,Y);
            %draw CO2 bar graph
            Y1 = [ app.report.(selected_process).Input.CO2 app.report_hold.(selected_process).Input.CO2 ];
            Y2 = [ app.report.(selected_process).Main.CO2 app.report_hold.(selected_process).Main.CO2 ];
            Y3 = [ app.report.(selected_process).Byproduct.CO2 app.report_hold.(selected_process).Byproduct.CO2 ];
            Y4 = [ app.report.(selected_process).Work.CO2 app.report_hold.(selected_process).Work.CO2 ];
            Y = [Y1 ; Y2 ; Y3 ; Y4];
            X = categorical({'Input','Main','Byproduct','Work'});
            X = reordercats(X,{'Input','Main','Byproduct','Work'});
            bar(app.UIAxes_co2,X,Y);
            %draw legend
            Y = [1 1];
            X = categorical({'_'});
            %X = categorical({'Current simulation','Baseline scenario (from paper or scenario when HOLD last pressed'});
            %X = reordercats(X,{'Current simulation','Baseline scenario (from paper or scenario when HOLD last pressed'});
            bar(app.UIAxes_legend1,X,Y);
            bar(app.UIAxes_legend1_2,X,Y);
        end

        function set_energy_mix(app)
            %set the energy mix of the sliders for grid components and
            %numerical fields
            app.EnergyMixGas.Value = app.energy_mix(1);
            app.EnergyMixWind.Value = app.energy_mix(2);
            app.EnergyMixCoal.Value = app.energy_mix(3);
            app.EnergyMixNuclear.Value = app.energy_mix(4);
            app.EnergyMixSolar.Value = app.energy_mix(5);

            app.man_gas.Value = round(app.energy_mix(1),1);
            app.man_wind.Value = round(app.energy_mix(2),1);
            app.man_coal.Value = round(app.energy_mix(3),1);
            app.man_nuclear.Value = round(app.energy_mix(4),1);
            app.man_solar.Value = round(app.energy_mix(5),1);
        end

        function balance_energy_mix(app,index,value)
            %balance energy grid when a slider is changed
            %TODO implement buttons to lock values
            energy_mix_temp = [0 0 0 0];
            if value > 99.9
                value = 99.9; %divide by zero error
            end

            % total < 100%, redistribute amongst other sliders
            if (value + sum(app.energy_mix) - app.energy_mix(index) < 100 )
                remainder_to_distribute = (100 - value - sum(app.energy_mix) + app.energy_mix(index)) / 4;
                app.energy_mix = app.energy_mix + remainder_to_distribute;
                app.energy_mix(index) = value;
                % total exactly 100%, set value
            elseif (value + sum(app.energy_mix) - app.energy_mix(index) == 100 )
                app.energy_mix(index) = value;
            else
                % total > 100%, reduce other values
                remainder_to_distribute = (100 - (value + sum(app.energy_mix) - app.energy_mix(index)));
                for i = 1:5
                    remainder_to_minus = -remainder_to_distribute * (app.energy_mix(i)/(sum(app.energy_mix) - app.energy_mix(index)));
                    energy_mix_temp(i) = remainder_to_minus;
                end
                app.energy_mix = app.energy_mix - energy_mix_temp;
                app.energy_mix(index) = value;
            end
            set_energy_mix(app);
            run(app);
        end

        function draw_machine_statistics(app)
            %Plot Spider Diagram
            %TODO make % of max values

            cla(app.UIAxes_machines);
            hold(app.UIAxes_machines,'on');

            calculate_machine_stats(app);
           
            data = [ app.multiplier_CO2e ...
                     app.multiplier_PurchaseCost ...
                     app.multiplier_ProcessRate ...
                     app.multiplier_EnergyConsumption ...
                     app.multiplier_GasConsumption];
            data = data / 2;
            % 
            %Calculate spider diagram input
            % 
            % data = [((app.emissionmultiplierEditField.Value / app.MachineQuantityEditField.Value) / (app.machine_database_original.(app.MachinesListBox.Value).CO2e * 2)) ...
            %     ((app.PurchaseCostEditField.Value / app.MachineQuantityEditField.Value) / (app.machine_database_original.(app.MachinesListBox.Value).PurchaseCost * 2))...
            %     ((app.ProcessRatetonhEditField.Value / app.MachineQuantityEditField.Value) / (app.machine_database_original.(app.MachinesListBox.Value).ProcessRate * 2))...
            %     ((app.ElectricityConsumptionMJhEditField.Value / app.MachineQuantityEditField.Value) / (app.machine_database_original.(app.MachinesListBox.Value).EnergyConsumption * 2))...
            %     ((app.GasConsumptionMJhEditField.Value / app.MachineQuantityEditField.Value) / (app.machine_database_original.(app.MachinesListBox.Value).GasConsumption * 2))];

            % pentagon angles
            x_data = [0 , cosd(18) * data(2) ,  sind(36) * data(3) , -sind(36) * data(4) , -cosd(18) * data(5)];
            y_data = [data(1) , sind(18) * data(2) , -cosd(36) * data(3) , -cosd(36) * data(4) ,  sind(18) * data(5)];
            x_lines = [0 , cosd(18) * 1 ,  sind(36) * 1 , -sind(36) * 1 , -cosd(18) * 1];
            y_lines = [1 , sind(18) * 1 , -cosd(36) * 1 , -cosd(36) * 1 ,  sind(18) * 1];

            if app.GasConsumptionMJhEditField.Value == 0
                x_data(5) = [];
                y_data(5) = [];
            end

            for i = 1 : 5
                plot(app.UIAxes_machines,[0 x_lines(i)],[0 y_lines(i)],'k');
            end

            pgon = polyshape(x_lines,y_lines);
            plot(app.UIAxes_machines,pgon,'FaceColor','blue','FaceAlpha',0.1);
            pgon2 = polyshape(x_data,y_data);
            plot(app.UIAxes_machines,pgon2,'FaceColor','black');
            %plot(app.UIAxes_machines,x_data,y_data,'xk','LineWidth',5);


            %also plot dashed pentagon for "normal" machine values
            x_normal = [0 , 0.4755 , 0.2939 , -0.2939 , -0.4755];
            y_normal = [0.5000 , 0.1545 , -0.4045 , -0.4045 , 0.1545];
            pgon3 = polyshape(x_normal,y_normal);

            plot(app.UIAxes_machines,pgon3,'LineStyle','--','FaceAlpha',0);
            
            colour = [0 0 0];
            for i = 1:numel(x_data)
                if data(i) > 0.5
                    colour = [1 0 0];
                elseif data(i) < 0.5
                    colour = [0 1 0];
                else
                    colour = [0 0 0];
                end
            plot(app.UIAxes_machines,x_data(i),y_data(i),'o','lineWidth',3,'MarkerFaceColor',colour,'MarkerEdgeColor',colour);
            end

            set(app.UIAxes_machines,'DataAspectRatio',[1 1 1]);
            axis(app.UIAxes_machines,'off')
            text(app.UIAxes_machines,x_lines(1),y_lines(1),'Emission \newlineMultiplier','HorizontalAlignment','center','VerticalAlignment','bottom');
            text(app.UIAxes_machines,x_lines(2),y_lines(2),'Purchase \newlineCost','HorizontalAlignment','left','VerticalAlignment','middle');
            text(app.UIAxes_machines,x_lines(3),y_lines(3),'Inverse \newlineProcess Rate','HorizontalAlignment','left','VerticalAlignment','top');
            text(app.UIAxes_machines,x_lines(4),y_lines(4),'Electricity \newlineConsumption','HorizontalAlignment','right','VerticalAlignment','top');
            if app.GasConsumptionMJhEditField.Value == 0 %no gas, don't plot label
                text(app.UIAxes_machines,x_lines(5),y_lines(5),'No Gas \newlineConsumption','HorizontalAlignment','right','VerticalAlignment','middle','Color',"#A2142F");
            else %gas, plot label
                text(app.UIAxes_machines,x_lines(5),y_lines(5),'Gas \newlineConsumption','HorizontalAlignment','right','VerticalAlignment','middle');
            end
            hold(app.UIAxes_machines,'off');
            app.machine_name_label.Text = app.MachinesListBox.Value;
        end

        function draw_transport_results(app)
            %app.DistancekmEditField.Value = app.Transport.(app.TransportListBox.Value).Distance;
            app.dist_cow_fact.Value = app.Transport.Milk_to_Factory.Distance;
            app.dist_beet_fact.Value = app.Transport.SugarBeet_to_SugarFactory.Distance;
            app.dist_stra_fact.Value = app.Transport.Strawberry_to_JamFactory.Distance;
            app.dist_fact_shop.Value = app.Transport.Distribution_to_Shop.Distance;
            app.NumberofjourneysEditField.Value = app.transport_results(app.TransportListBox.Value,8).(1);
            app.FuelconsumptionEditField.Value = app.transport_results(app.TransportListBox.Value,10).(1) * app.transport_results(app.TransportListBox.Value,8).(1) * app.transport_results(app.TransportListBox.Value,4).(1);
            app.Numberofjourneys_total_EditField.Value = app.transport_totals.journeys;
            app.Fuelconsumption_total_EditField.Value = app.transport_totals.journeys * app.transport_totals.consumption * app.transport_totals.distance;

            %YAY bar() function isn't intuitive at all!
            X = categorical({'Totals','Hold'});
            X = reordercats(X,{'Totals','Hold'});
            b = bar(app.UIAxes_t_load,X,[app.transport_results(app.TransportListBox.Value,1).(1) app.transport_results_hold(app.TransportListBox.Value,1).(1)]);b.FaceColor = 'flat';b.CData(2,:) = [0.8500, 0.3250, 0.0980];b.BarWidth = 0.5;
            b = bar(app.UIAxes_t_CO2,X,[app.transport_results(app.TransportListBox.Value,7).(1) app.transport_results_hold(app.TransportListBox.Value,7).(1)]);b.FaceColor = 'flat';b.CData(2,:) = [0.8500, 0.3250, 0.0980];b.BarWidth = 0.5;
            b = bar(app.UIAxes_t_CExC,X,[app.transport_results(app.TransportListBox.Value,5).(1) app.transport_results_hold(app.TransportListBox.Value,5).(1)]);b.FaceColor = 'flat';b.CData(2,:) = [0.8500, 0.3250, 0.0980];b.BarWidth = 0.5;
            b = bar(app.UIAxes_t_CEnC,X,[app.transport_results(app.TransportListBox.Value,6).(1) app.transport_results_hold(app.TransportListBox.Value,6).(1)]);b.FaceColor = 'flat';b.CData(2,:) = [0.8500, 0.3250, 0.0980];b.BarWidth = 0.5;

            X = categorical({'Totals','Hold'});
            X = reordercats(X,{'Totals','Hold'});
            b = bar(app.UIAxes_t_load_2,X,[app.transport_totals.Load app.transport_totals_hold.Load]);b.FaceColor = 'flat';b.CData(2,:) = [0.8500, 0.3250, 0.0980];b.BarWidth = 0.5;
            b = bar(app.UIAxes_t_CO2_2,X,[app.transport_totals.CO2 app.transport_totals_hold.CO2]);b.FaceColor = 'flat';b.CData(2,:) = [0.8500, 0.3250, 0.0980];b.BarWidth = 0.5;
            b = bar(app.UIAxes_t_CExC_2,X,[app.transport_totals.CExC app.transport_totals_hold.CExC]);b.FaceColor = 'flat';b.CData(2,:) = [0.8500, 0.3250, 0.0980];b.BarWidth = 0.5;
            b = bar(app.UIAxes_t_CEnC_2,X,[app.transport_totals.CEnC app.transport_totals_hold.CEnC]);b.FaceColor = 'flat';b.CData(2,:) = [0.8500, 0.3250, 0.0980];b.BarWidth = 0.5;
            app.DistancekmEditField.Value = app.Transport.(app.TransportListBox.Value).Distance;
        end

        function draw_process_diagram(app)
            %master function, calls other functions for flow charts
            value = app.ProcessListBox.Value;
            app.PD_hide_labels();
            switch value
                case 'Milk'
                    app.PD_plot_milk();
                case 'Yoghurt'
                    app.PD_plot_yoghurt();
                case 'Strawberry'
                    app.PD_plot_strawberry();
                case 'Sugar'
                    app.PD_plot_sugar();
                case 'Strawberry Jam'
                    app.PD_plot_strawberry_jam();
                case 'Flavoured Yoghurt'
                    app.PD_plot_flavoured_yoghurt();
                otherwise
                    %error
            end
        end

        function draw_grid_composition(app)
            %draw garph of national grid composition
            Y_data = [app.Grid_Composition.nuclear ...
                app.Grid_Composition.coal2 ...
                app.Grid_Composition.wind ...
                app.Grid_Composition.solar2 ...
                app.Grid_Composition.gas...
                ];
            X_data = app.Grid_Composition.Time;
            area(app.GridPlot,X_data,Y_data);
            legend(app.GridPlot,'Nuclear','Coal','Wind','Solar','Gas','Location','northoutside','Box','off')
        end

        function PD_plot_milk(app)
            %Figure 2 in paper
            app.PDimage.ImageSource = 'res\PD_milk.png';
            app.M_1.Visible = 'on';
            app.M_2.Visible = 'on';
            app.proc_M_production.Visible = 'on';
            app.M_4.Visible = 'on';
            app.M_5.Visible = 'on';
            app.M_6x.Visible = 'on';

            app.M_1.Text = 'Feed ' + string(app.FC_data.milk.feed) + 'kg';
            app.M_2.Text = 'Diesel Oil ' + string(app.FC_data.milk.diesel) + 'kg';
            %app.proc_M_production.Text = '';
            app.M_4.Text = 'Calves ' + string(app.FC_data.milk.calves) + 'kg';
            app.M_5.Text = 'Raw Milk ' + string(app.FC_data.milk.raw_milk) + 'kg';
            %app.M_6x.Text = '';
        end

        function PD_plot_yoghurt(app)
            %Figure 3 in paper
            app.PDimage.ImageSource = 'res\PD_yoghurt.png';
            app.Y_1.Visible = 'on';
            app.Y_2.Visible = 'on';
            app.proc_Y_fat_separation.Visible = 'on';
            app.Y_4.Visible = 'on';
            app.Y_5.Visible = 'on';
            app.Y_6.Visible = 'on';
            app.proc_Y_spray_drying.Visible = 'on';
            app.Y_8.Visible = 'on';
            app.Y_9.Visible = 'on';
            app.proc_Y_production.Visible = 'on';
            app.Y_11.Visible = 'on';
            app.proc_Y_microbial.Visible = 'on';
            app.Y_13.Visible = 'on';
            app.Y_14.Visible = 'on';
            app.Y_15.Visible = 'on';
            app.Y_16.Visible = 'on';
            app.Y_1.Text = 'Raw Milk ' + string(app.FC_data.milk.raw_milk) + 'kg';
            app.Y_2.Text = 'Electricity ' + string(app.FC_data.yogh.elec1) + 'MJ';
            %app.proc_Y_fat_separation.Text = '';
            app.Y_4.Text = 'Fat ' + string(app.FC_data.yogh.fat) + 'kg';
            app.Y_5.Text = 'Electricity ' + string(app.FC_data.yogh.elec2) + 'MJ';
            app.Y_6.Text = 'Skim Milk ' + string(app.FC_data.yogh.skim_milk) + 'kg';
            %app.proc_Y_spray_drying.Text = '';
            app.Y_8.Text = 'Water ' + string(app.FC_data.yogh.water) + 'kg';
            app.Y_9.Text = 'Milk Powder ' + string(app.FC_data.yogh.milk_pow) + 'kg';
            %app.proc_Y_production.Text = '';
            app.Y_11.Text = 'Electricity ' + string(app.FC_data.yogh.elec3) + 'MJ';
            %app.proc_Y_microbial.Text = '';
            app.Y_13.Text = 'Microbial Culture ' + string(app.FC_data.yogh.microb) + 'kg';
            app.Y_14.Text = 'Non-fat Yoghurt ' + string(app.FC_data.yogh.non_fat_yo) + 'kg';
            app.Y_15.Text = 'Natural Gas ' + string(app.FC_data.yogh.gas) + 'kg';
            app.Y_16.Text = 'Electricity ' + string(app.FC_data.yogh.elec4) + 'MJ';
        end

        function PD_plot_sugar(app)
            %Figure 5 in paper
            app.PDimage.ImageSource = 'res\PD_sugar.png';
            app.S_1.Visible = 'on';
            app.S_2.Visible = 'on';
            app.S_3.Visible = 'on';
            app.S_4.Visible = 'on';
            app.proc_S_sugar_beet_production.Visible = 'on';
            app.S_6.Visible = 'on';
            app.S_7.Visible = 'on';
            app.S_8.Visible = 'on';
            app.proc_S_cleaning_sorting.Visible = 'on';
            app.S_10.Visible = 'on';
            app.S_11.Visible = 'on';
            app.S_12.Visible = 'on';
            app.proc_SB_recycling.Visible = 'on';
            app.S_14.Visible = 'on';
            app.S_15.Visible = 'on';
            app.S_16.Visible = 'on';
            app.proc_S_sugar_production.Visible = 'on';
            app.S_18.Visible = 'on';
            app.proc_S_packaging.Visible = 'on';
            app.S_20.Visible = 'on';
            app.S_21.Visible = 'on';
            app.S_22.Visible = 'on';

            app.S_1.Text = 'Pesticides ' + string(app.FC_data.sugar.pest) + 'kg';
            app.S_2.Text = 'Diesel Oil ' + string(app.FC_data.sugar.diesel) + 'kg';
            app.S_3.Text = 'Fertilizers ' + string(app.FC_data.sugar.fert) + 'kg';
            app.S_4.Text = 'Electricity ' + string(app.FC_data.sugar.elec1) + 'MJ';
            %app.proc_S_sugar_beet_production.Text = '';
            %app.S_6.Text = '';
            app.S_7.Text = 'Raw Sugar Beet ' + string(app.FC_data.sugar.beetraw) + 'kg';
            app.S_8.Text = 'Electricity ' + string(app.FC_data.sugar.elec2) + 'MJ'; %check
            %app.proc_S_cleaning_sorting.Text = '';
            app.S_10.Text = 'Sugar Beet ' + string(app.FC_data.sugar.beetclean) + 'kg';
            app.S_11.Text = 'Waste Sugar Beet ' + string(app.FC_data.sugar.beetclean) + 'kg';
            app.S_12.Text = 'Diesel oil (transport) ' + string() + 'kg';
            %app.proc_SB_recycling.Text = '';
            app.S_14.Text = 'Electricity ' + string(app.FC_data.sugar.elec3) + 'MJ'; %check
            app.S_15.Text = 'Natural Gas ' + string(app.FC_data.sugar.gas) + 'kg';
            app.S_16.Text = 'Polylactic Acid ' + string(app.FC_data.sugar.poly) + 'kg';
            %app.proc_S_sugar_production.Text = '';
            app.S_18.Text = 'Electricity ' + string(app.FC_data.sugar.elec4) + 'MJ';
            %app.proc_S_packaging.Text = '';
            app.S_20.Text = 'Polylactic Acid ' + string(app.FC_data.sugar.poly2) + 'kg';
            app.S_21.Text = 'Diesel oil (transport) ' + string() + 'kg';
            app.S_22.Text = 'Sugar ' + string(app.FC_data.sugar.sugar) + 'kg';
        end

        function PD_plot_strawberry_jam(app)
            %Figure 6 in paper
            app.PDimage.ImageSource = 'res\PD_strawberry_jam.png';
            app.SJ_1.Visible = 'on';
            app.SJ_2.Visible = 'on';
            app.SJ_3.Visible = 'on';
            app.proc_SJ_jam_making.Visible = 'on';
            app.SJ_5.Visible = 'on';
            app.SJ_6.Visible = 'on';
            app.SJ_7.Visible = 'on';
            app.SJ_8.Visible = 'on';

            app.SJ_1.Text = 'Natural Gas ' + string(app.FC_data.jam.gas) + 'kg'; %check
            app.SJ_2.Text = 'Polylactic Acid ' + string(app.FC_data.jam.poly) + 'kg';
            app.SJ_3.Text = 'Electricity ' + string(app.FC_data.jam.elec) + 'MJ';
            %app.proc_SJ_jam_making.Text = '';
            app.SJ_5.Text = 'Sugar ' + string(app.FC_data.jam.sugar) + 'kg';
            app.SJ_6.Text = 'Strawberries ' + string(app.FC_data.jam.strawbs) + 'kg';
            app.SJ_7.Text = 'Diesel oil (transport) ' + string() + 'kg';
            app.SJ_8.Text = 'Jam ' + string(app.FC_data.jam.jam) + 'kg';
        end

        function PD_plot_strawberry(app)
            %Figure 4 in paper
            app.PDimage.ImageSource = 'res\PD_strawberry.png';
            app.SB_1.Visible = 'on';
            app.SB_2.Visible = 'on';
            app.SB_3.Visible = 'on';
            app.SB_4.Visible = 'on';
            app.SB_5x.Visible = 'on';
            app.proc_SB_production.Visible = 'on';
            app.SB_7.Visible = 'on';
            app.proc_S_recycling.Visible = 'on';
            app.SB_9.Visible = 'on';
            app.SB_10.Visible = 'on';
            app.SB_11.Visible = 'on';
            app.proc_SB_clean_sort.Visible = 'on';
            app.SB_13.Visible = 'on';
            app.SB_14.Visible = 'on';
            app.SB_15.Visible = 'on';
            app.SB_16.Visible = 'on';

            app.SB_1.Text = 'Diesel Oil ' + string(app.FC_data.strawb.diesel) + 'kg';
            app.SB_2.Text = 'Electricity ' + string(app.FC_data.strawb.elec1) + 'kg';
            app.SB_3.Text = 'Pesticides ' + string(app.FC_data.strawb.pest) + 'kg';
            app.SB_4.Text = 'Fertilizers ' + string(app.FC_data.strawb.fert) + 'kg';
            app.SB_5x.Text = 'Organic Manure';
            %app.proc_SB_production.Text = '';
            app.SB_7.Text = 'Micronutrients  '+ string(app.FC_data.strawb.micron) + 'kg';
            %app.proc_S_recycling.Text = '';
            app.SB_9.Text = 'Raw Strawberries ' + string(app.FC_data.strawb.raw) + 'kg';
            app.SB_10.Text = 'Polylactic Acid ' + string(app.FC_data.strawb.poly) + 'kg';
            app.SB_11.Text = 'Waste Strawberries ' + string(app.FC_data.strawb.waste) + 'kg';
            %app.proc_SB_clean_sort.Text = '';
            app.SB_13.Text = 'Electricity ' + string(app.FC_data.strawb.elec2) + 'MJ';
            app.SB_14.Text = 'Electricity ' + string(app.FC_data.strawb.elec3) + 'MJ';
            app.SB_15.Text = 'Strawberries ' + string(app.FC_data.strawb.cleaned) + 'kg';
            app.SB_16.Text = 'Diesel oil (transport) ' + string() + 'kg'; %need to check this
        end

        function PD_plot_flavoured_yoghurt(app)
            %Figure 7 in paper
            app.PDimage.ImageSource = 'res\PD_flavoured_yoghurt.png';
            app.FY_1.Visible = 'on';
            app.FY_2.Visible = 'on';
            app.proc_FY_making.Visible = 'on';
            app.FY_4.Visible = 'on';
            app.FY_5.Visible = 'on';
            app.proc_FY_packaging.Visible = 'on';
            app.FY_7x.Visible = 'on';
            app.FY_8.Visible = 'on';
            app.FY_9.Visible = 'on';

            app.FY_1.Text = 'Electricity ' + string(app.FC_data.flav_yogh.elec) + 'MJ';
            app.FY_2.Text = 'Non-fat yoghurt ' + string(app.FC_data.yogh.non_fat_yo) + 'kg';
            %app.proc_FY_making.Text = '';
            app.FY_4.Text = 'Jam ' + string(app.FC_data.jam.jam) + 'kg';
            app.FY_5.Text = 'Polylactic Acid ' + string(app.FC_data.flav_yogh.poly) + 'kg';
            %app.proc_FY_packaging.Text = '';
            %app.FY_7x.Text = '';
            app.FY_8.Text = 'Diesel oil (transport) kg';
            app.FY_9.Text = 'Flavored Yoghurt ' + string(app.FC_data.flav_yogh.flav_yogh) + 'kg';
        end

        function PD_hide_labels(app)
            %hide all labels, then redraw only the ones you need
            app.Y_1.Visible = 'off';
            app.Y_2.Visible = 'off';
            app.proc_Y_fat_separation.Visible = 'off';
            app.Y_4.Visible = 'off';
            app.Y_5.Visible = 'off';
            app.Y_6.Visible = 'off';
            app.proc_Y_spray_drying.Visible = 'off';
            app.Y_8.Visible = 'off';
            app.Y_9.Visible = 'off';
            app.proc_Y_production.Visible = 'off';
            app.Y_11.Visible = 'off';
            app.proc_Y_microbial.Visible = 'off';
            app.Y_13.Visible = 'off';
            app.Y_14.Visible = 'off';
            app.Y_15.Visible = 'off';
            app.Y_16.Visible = 'off';
            app.S_1.Visible = 'off';
            app.S_2.Visible = 'off';
            app.S_3.Visible = 'off';
            app.S_4.Visible = 'off';
            app.proc_S_sugar_beet_production.Visible = 'off';
            app.S_6.Visible = 'off';
            app.S_7.Visible = 'off';
            app.S_8.Visible = 'off';
            app.proc_S_cleaning_sorting.Visible = 'off';
            app.S_10.Visible = 'off';
            app.S_11.Visible = 'off';
            app.S_12.Visible = 'off';
            app.proc_SB_recycling.Visible = 'off';
            app.S_14.Visible = 'off';
            app.S_15.Visible = 'off';
            app.S_16.Visible = 'off';
            app.proc_S_sugar_production.Visible = 'off';
            app.S_18.Visible = 'off';
            app.proc_S_packaging.Visible = 'off';
            app.S_20.Visible = 'off';
            app.S_21.Visible = 'off';
            app.S_22.Visible = 'off';
            app.SJ_1.Visible = 'off';
            app.SJ_2.Visible = 'off';
            app.SJ_3.Visible = 'off';
            app.proc_SJ_jam_making.Visible = 'off';
            app.SJ_5.Visible = 'off';
            app.SJ_6.Visible = 'off';
            app.SJ_7.Visible = 'off';
            app.SJ_8.Visible = 'off';
            app.SB_1.Visible = 'off';
            app.SB_2.Visible = 'off';
            app.SB_3.Visible = 'off';
            app.SB_4.Visible = 'off';
            app.SB_5x.Visible = 'off';
            app.proc_SB_production.Visible = 'off';
            app.SB_7.Visible = 'off';
            app.proc_S_recycling.Visible = 'off';
            app.SB_9.Visible = 'off';
            app.SB_10.Visible = 'off';
            app.SB_11.Visible = 'off';
            app.proc_SB_clean_sort.Visible = 'off';
            app.SB_13.Visible = 'off';
            app.SB_14.Visible = 'off';
            app.SB_15.Visible = 'off';
            app.SB_16.Visible = 'off';
            app.M_1.Visible = 'off';
            app.M_2.Visible = 'off';
            app.proc_M_production.Visible = 'off';
            app.M_4.Visible = 'off';
            app.M_5.Visible = 'off';
            app.M_6x.Visible = 'off';
            app.FY_1.Visible = 'off';
            app.FY_2.Visible = 'off';
            app.proc_FY_making.Visible = 'off';
            app.FY_4.Visible = 'off';
            app.FY_5.Visible = 'off';
            app.proc_FY_packaging.Visible = 'off';
            app.FY_7x.Visible = 'off';
            app.FY_8.Visible = 'off';
            app.FY_9.Visible = 'off';
        end

        function load_machine(app)
            %load machine given a value from MachineChoice listbox
            machineupdater.MachineChoice = app.machine_database.(app.MachinesListBox.Value).MachineChoice;
            machineupdater.MachineQuantity = app.machine_database.(app.MachinesListBox.Value).MachineQuantity;

            calculate_machine_stats(app);

            num_machines = machineupdater.MachineQuantity;
            %calculate new machine stats
            machineupdater.CO2e = app.machine_database_original.(app.MachinesListBox.Value).CO2e * app.multiplier_CO2e * num_machines;
            machineupdater.PurchaseCost = app.machine_database_original.(app.MachinesListBox.Value).PurchaseCost  * app.multiplier_PurchaseCost * num_machines;
            machineupdater.ProcessRate = app.machine_database_original.(app.MachinesListBox.Value).ProcessRate  * (0-app.multiplier_ProcessRate+2.5) * num_machines;
            machineupdater.EnergyConsumption = app.machine_database_original.(app.MachinesListBox.Value).EnergyConsumption  * app.multiplier_EnergyConsumption * num_machines;
            machineupdater.GasConsumption = app.machine_database_original.(app.MachinesListBox.Value).GasConsumption * app.multiplier_GasConsumption * num_machines;

            %update machine stat display information
            app.emissionmultiplierEditField.Value = machineupdater.CO2e;
            app.PurchaseCostEditField.Value = machineupdater.PurchaseCost;
            app.ProcessRatetonhEditField.Value = machineupdater.ProcessRate;
            app.ElectricityConsumptionMJhEditField.Value = machineupdater.EnergyConsumption;
            app.GasConsumptionMJhEditField.Value = machineupdater.GasConsumption;
            app.MachineQuantityEditField.Value =  machineupdater.MachineQuantity;
            app.MachineSupplierSpinner.Value =  machineupdater.MachineChoice;

            %write machine stats to database
            if app.machine_all_btn.Value == true
                % update ALL machines
                fields = fieldnames(app.machine_database);
                for i = 1 : numel(fields)
                    %if( isnumeric(app.machine_database.(fields{i})))
                    app.machine_database.(fields{i}).CO2e = app.machine_database_original.(fields{i}).CO2e * app.multiplier_CO2e * num_machines;
                    app.machine_database.(fields{i}).PurchaseCost = app.machine_database_original.(fields{i}).PurchaseCost  * app.multiplier_PurchaseCost * num_machines;
                    app.machine_database.(fields{i}).ProcessRate = app.machine_database_original.(fields{i}).ProcessRate  * app.multiplier_ProcessRate * num_machines;
                    app.machine_database.(fields{i}).EnergyConsumption = app.machine_database_original.(fields{i}).EnergyConsumption  * app.multiplier_EnergyConsumption * num_machines;
                    app.machine_database.(fields{i}).GasConsumption = app.machine_database_original.(fields{i}).GasConsumption * app.multiplier_GasConsumption * num_machines;
                    app.machine_database.(fields{i}).MachineChoice = machineupdater.MachineChoice;
                    app.machine_database.(fields{i}).MachineQuantity = machineupdater.MachineQuantity;
                    %end
                end
            else
                % update only one machine
                app.machine_database.(app.MachinesListBox.Value).CO2e = machineupdater.CO2e;
                app.machine_database.(app.MachinesListBox.Value).PurchaseCost = machineupdater.PurchaseCost;
                app.machine_database.(app.MachinesListBox.Value).ProcessRate = machineupdater.ProcessRate;
                app.machine_database.(app.MachinesListBox.Value).EnergyConsumption = machineupdater.EnergyConsumption;
                app.machine_database.(app.MachinesListBox.Value).GasConsumption = machineupdater.GasConsumption;
                app.machine_database.(app.MachinesListBox.Value).MachineQuantity = machineupdater.MachineQuantity;
                app.machine_database.(app.MachinesListBox.Value).MachineChoice = machineupdater.MachineChoice;
            end
        end


        function final_product_value_changed(app,value)
            %make slider and input box values the same, avoid
            %unneccesary changes to avoid triggering this function twice
            if app.final_product_Slider.Value ~= value
                app.final_product_Slider.Value = value;
            end
            if app.final_product_numericalEditField_2.Value ~= value
                app.final_product_numericalEditField_2.Value = value;
            end
            app.misc.final_product = value;
            run(app);
        end

        function draw_process_timings(app)
            %plot process timings
            %TODO tidy this up and add bar lines
            b = barh(app.TimingAxes,app.process_timing_results,'stacked');
            b(1).FaceColor = [1 1 1];
            b(1).EdgeColor = [1 1 1];
            set(app.TimingAxes,'yticklabel',{'Skimmed Milk', 'Powdered Milk', 'Microbial Culture', 'Yogurt Plain', 'SugarBeet Cleaned', 'SugarBeet Ground Waste', 'Sugar Processed', 'Sugar Processed Packed', 'Strawberries Cleaned', 'Strawberries Ground Waste', 'Strawberries Pulper', 'Jam Blender', 'Jam Evaporator', 'Jam Filling', 'Yogurt Mixing', 'Yogurt Filling'});
            set(app.TimingAxes,'ytick',[1,2,3,4,5,6,7,8,9,10,11,12,13,14,15,16]);
            xlim(app.TimingAxes,[0 24]);
        end

        function draw_summary(app)
            %SANITIZE INPUT, apply ABS to all structs in app.report
            fields = fieldnames(app.report);
            fields2 = {'Input','Main','Byproduct','Work'};
            fields3 = {'CEnC','CExC','CO2'};
            for i = 1 : (numel(fields) - 1)
                for j = 1 : numel(fields2)
                    for k = 1 : numel(fields3)
                        if isempty(app.report.(fields{i}).(fields2{j}).(fields3{k}))
                            %app.report.(fields{i}).(fields2{j}).(fields3{k}) = 0;
                        else
                            app.report.(fields{i}).(fields2{j}).(fields3{k}) = abs(app.report.(fields{i}).(fields2{j}).(fields3{k}));
                        end
                    end
                end
            end

            x1 = categorical({'A','B','C','D'});
            x1 = reordercats(x1,{'A','B','C','D'});

            x2 = categorical({'A','B','C','E','F','D'});
            x2 = reordercats(x2,{'A','B','C','E','F','D'});

            % fig 8
            y8 = [ ...
                app.report.Skimmed_Milk_Production.Input.CEnC...
                app.FC_data.System_Processes.Raw_Milk.Fuel_Type.CEnC...
                app.report.Skimmed_Milk_Production.Main.CEnC; ....

                app.report.Powdered_Milk_Production.Input.CEnC...
                app.FC_data.Electricity_Inputs.Powdered_Milk.CEnC...
                app.report.Powdered_Milk_Production.Main.CEnC ; ...

                app.report.Plain_Yogurt_Production.Input.CEnC...
                app.FC_data.GasSystem_Inputs.Yogurt_Plain.Requirement * app.FC_data.Gas_CEnC...
                app.report.Plain_Yogurt_Production.Main.CEnC ;...

                app.report.Yogurt_Packing.Input.CEnC...
                app.FC_data.System_Processes.Yogurt_Filling.Machine_EnergyConsumption + app.FC_data.Transport.Distribution_to_Shop.Object.Fuel_CEnC...
                app.report.Yogurt_Packing.Main.CEnC...
                ];
            %y8 = abs(y8);
            bar(app.fig8,x1,y8)
            app.fig8.XAxis.TickLength = [0 0];
            legend(app.fig8,'Energy, raw materials (MJ)','Energy, fuels (MJ)','Energy, main product (MJ)','Location','eastoutside','fontsize',8,'Box','off');

            beets = app.FC_data.System_Processes.SugarBeet.Work_Products{1, 1}.CEnC + app.FC_data.Transport.Distribution_to_Shop.Object.Fuel_CEnC + app.report.Raw_SugarBeet_Production.Work.CEnC;
            % fig 9
            y9 = [...
                app.FC_data.System_Processes.Raw_Milk.Fuel_Type.CEnC ...
                app.FC_data.System_Processes.Raw_Milk.Fuel_Type.CEnC / app.report.Skimmed_Milk_Production.Main.Mdot*1000;...

                app.FC_data.Electricity_Inputs.Powdered_Milk.CEnC...
                app.FC_data.Electricity_Inputs.Powdered_Milk.CEnC / app.report.Powdered_Milk_Production.Main.Mdot*1000;...

                app.FC_data.GasSystem_Inputs.Yogurt_Plain.Requirement * app.FC_data.Gas_CEnC...
                app.FC_data.GasSystem_Inputs.Yogurt_Plain.Requirement * app.FC_data.Gas_CEnC / app.report.Plain_Yogurt_Production.Main.Mdot*1000;...

                app.FC_data.System_Processes.Strawberries.Work_Products{1, 1}.CEnC...
                app.FC_data.System_Processes.Strawberries.Work_Products{1, 1}.CEnC / app.report.Strawberry_Cleaning.Main.Mdot*1000;...


                beets ...
                beets / app.report.Sugar_Packing.Main.Mdot*1000;...

                app.report.Yogurt_Packing.Work.CEnC...
                app.report.Yogurt_Packing.Work.CEnC / app.report.Yogurt_Packing.Main.Mdot*1000;...
                ];
            %y9 = abs(y9);
            bar(app.fig9,x2,y9)
            app.fig9.XAxis.TickLength = [0 0];
            legend(app.fig9,'Total energy, fuels (MJ)','Specific energy, fuels (MJ/ton)','Location','eastoutside','fontsize',8,'Box','off');


            app.report.Powdered_Milk_Production.Input.CExC;
            app.FC_data.System_Processes.Powdered_Milk.Inputs_Named.CEnC;

            % fig 10
            y10 = [ ...
                app.report.Milk_Production.Input.CExC...
                app.report.Skimmed_Milk_Production.Main.CExC...
                app.report.Milk_Production.Main.CExC...
                app.report.Milk_Production.Input.CExC + app.report.Skimmed_Milk_Production.Main.CExC - app.report.Milk_Production.Main.CExC;...
                
                app.report.Powdered_Milk_Production.Input.CExC...
                app.report.Powdered_Milk_Production.Work.CExC...
                app.report.Powdered_Milk_Production.Main.CExC...
                app.report.Powdered_Milk_Production.Input.CExC + app.report.Powdered_Milk_Production.Work.CExC - app.report.Powdered_Milk_Production.Main.CExC;...
    
                app.report.Plain_Yogurt_Production.Input.CExC...
                app.FC_data.GasSystem_Inputs.Yogurt_Plain.Requirement * 48.7...
                app.report.Plain_Yogurt_Production.Main.CExC...
                app.report.Plain_Yogurt_Production.Input.CExC+(app.FC_data.GasSystem_Inputs.Yogurt_Plain.Requirement * 48.7)-app.report.Plain_Yogurt_Production.Main.CExC;...

                app.report.Yogurt_Packing.Input.CExC...
                app.report.Yogurt_Packing.Work.CExC...
                app.report.Yogurt_Packing.Main.CExC...
                app.report.Yogurt_Packing.Input.CExC+app.report.Yogurt_Packing.Work.CExC-app.report.Yogurt_Packing.Main.CExC...
                ];
            %y10 = abs(y10);
            bar(app.fig10,x1,y10)
            app.fig10.XAxis.TickLength = [0 0];
            legend(app.fig10,'Exergy, input chemical (MJ)','Exergy, input work (MJ)','Exergy, product (MJ)','Exergy, loss (MJ)','Location','eastoutside','fontsize',8,'Box','off');

            % fig 11
            y11 = [ ...
                y10(1,4)...
                y10(1,4)/app.report.Skimmed_Milk_Production.Main.Mdot*1000;...

                y10(2,4)...
                y10(2,4)/app.report.Powdered_Milk_Production.Main.Mdot*1000;...

                y10(3,4)...
                y10(3,4) / app.report.Plain_Yogurt_Production.Main.Mdot*1000;...

                y10(4,4)...
                y10(4,4)/app.report.Yogurt_Packing.Main.Mdot*1000;...
                ];
            %y11 = abs(y11);
            bar(app.fig11,x1,y11);
            app.fig11.XAxis.TickLength = [0 0];
            legend(app.fig11,'Exergy loss, total (MJ)','Exergy loss, specific (MJ/ton)','Location','eastoutside','fontsize',8,'Box','off');

            CO2_skim_milk = app.report.Milk_Production.Input.CO2 + app.report.Milk_Production.Work.CO2;
            CO2_powdered_milk = app.report.Powdered_Milk_Production.Input.CO2 + app.report.Powdered_Milk_Production.Work.CO2;
            CO2_plain_yoghurt = app.report.Plain_Yogurt_Production.Input.CO2 + app.report.Plain_Yogurt_Production.Work.CO2;
            CO2_strawberry = app.report.Raw_Strawberry_Production.Input.CO2 + app.report.Raw_Strawberry_Production.Work.CO2;
            CO2_beet = app.report.Sugar_Processed_Production.Input.CO2 + app.report.Sugar_Processed_Production.Work.CO2 / 2;
            CO2_packaging = app.report.Yogurt_Packing.Input.CO2 + app.report.Yogurt_Packing.Work.CO2;

            % fig 12
            y12 = [...
                CO2_skim_milk...
                CO2_skim_milk / app.report.Skimmed_Milk_Production.Main.Mdot*1000;...

                CO2_powdered_milk...
                CO2_powdered_milk / app.report.Powdered_Milk_Production.Main.Mdot*1000;...

                CO2_plain_yoghurt...
                CO2_plain_yoghurt / app.report.Plain_Yogurt_Production.Main.Mdot*1000;...

                CO2_strawberry...
                CO2_strawberry / app.report.Strawberry_Cleaning.Main.Mdot*1000;...

                CO2_beet / 50 ...
                CO2_beet; ...

                CO2_packaging...
                CO2_packaging / app.report.Yogurt_Packing.Main.Mdot*1000;...
                ];
            %y12 = abs(y12);
            bar(app.fig12,x2,y12)
            app.fig12.XAxis.TickLength = [0 0];
            legend(app.fig12,'CO2e, total (kg)','CO2e, specific (kg/ton)','Location','eastoutside','fontsize',8,'Box','off');

        end

        function calculate_score(app)

            %Sum CO2 calculations
            %%TODO add in contributions from machines
            fields = fieldnames(app.report);
            fields2 = {'Input','Main','Byproduct','Work'};

            for i = 1 : (numel(fields) - 1)
                CO2_sums.(fields{i}) = 0;
                for j = 1 : numel(fields2)
                        if isempty(app.report.(fields{i}).(fields2{j}).CO2)
                            %app.report.(fields{i}).(fields2{j}).(fields3{k}) = 0;
                        else
                            CO2_sums.(fields{i}) = CO2_sums.(fields{i}) + abs(app.report.(fields{i}).(fields2{j}).CO2);
                        end
                    
                end
            end

            sum_main = 0;
            sum_input = 0;
            sum_work = 0;
            sum_co2e = 0;
            %sum all  components
            for i = 1 : (numel(fields) - 1)
                sum_main = sum_main + app.report.(fields{i}).Main.CExC;
                sum_input = sum_input + app.report.(fields{i}).Input.CExC;
                sum_work = sum_work + app.report.(fields{i}).Work.CExC;
                sum_co2e = sum_co2e + CO2_sums.(fields{i});
            end
            
            %add transport CO2e if level 3
            if app.Level3Button.Value
            sum_co2e = sum_co2e + app.transport_totals.CO2;
            end

            %sum machine purchase costs
            sum_machines = 0;
            fields = fieldnames(app.machine_database);
            for i = 1 : numel(fields)
                sum_machines = sum_machines + app.machine_database.(fields{i}).PurchaseCost;
            end

            app.score.cop =  sum_main / (sum_work+sum_input) * 100;
            app.score.cop = round(app.score.cop,2)/2; %!
            app.score.co2e = round(sum_co2e);
            app.score.machine = round(sum_machines);

            %init base scores if first run
            if ~isfield(app.score,'cop_base')
                app.score.cop_base = app.score.cop;
                app.score.co2e_base = app.score.co2e;
                app.score.machine_base = app.score.machine;
                %write baseline scores under current scores
                app.score_CO2e_hold.Text = string(app.score.co2e);
                app.score_machine_hold.Text = string(app.score.machine);
                app.score_CDP_hold.Text = string(app.score.cop);
            end

            app.score_CDPEditField.Value = app.score.cop;
            app.score_CO2eEditField.Value = app.score.co2e;
            app.score_MachinecostEditField.Value = app.score.machine;

            %change score listbox backgrounds
            %red = worse than paper baseline
            %green = better than paper baseline
            if (app.score.cop > app.score.cop_base)
                app.score_CDPEditField.BackgroundColor = [0 1 0];
            elseif (app.score.cop < app.score.cop_base)
                app.score_CDPEditField.BackgroundColor = [1 0 0];
            else
                app.score_CDPEditField.BackgroundColor = [1 1 1];
            end

            if (app.score.co2e < app.score.co2e_base)
                app.score_CO2eEditField.BackgroundColor = [0 1 0];
            elseif (app.score.co2e > app.score.co2e_base)
                app.score_CO2eEditField.BackgroundColor = [1 0 0];
            else
                app.score_CO2eEditField.BackgroundColor = [1 1 1];
            end

            if (app.score.machine < app.score.machine_base)
                app.score_MachinecostEditField.BackgroundColor = [0 1 0];
            elseif (app.score.machine > app.score.machine_base)
                app.score_MachinecostEditField.BackgroundColor = [1 0 0];
            else
                app.score_MachinecostEditField.BackgroundColor = [1 1 1];
            end

        end

        function setEnableWithChildren(app, component, value)
            % Recursively set enable status for all items

            if isa(component, 'matlab.ui.control.Label') || isa(component, 'matlab.ui.control.Lamp')
                return % Don't enable labels, etc.
            end

            if isprop(component, 'Enable')
                if isa(component, 'matlab.ui.control.Table')
                    if value
                        component.Enable = 'on'; % Table uses on/off
                    else
                        component.Enable = 'off';
                    end
                else
                    component.Enable = value;
                end
            end

            if isprop(component, 'Children')
                children = allchild(component);
            else
                children = [];
            end
            for child = reshape(children, 1, [])
                app.setEnableWithChildren(child, value);
            end
        end

        
        function results = identify_machine_process(app)
            text_to_reset = [app.proc_Y_fat_separation,...
                app.proc_Y_spray_drying,...
                app.proc_Y_microbial,...
                app.proc_Y_production,...
                app.proc_S_cleaning_sorting,...
                app.proc_SB_clean_sort,...
                app.proc_SB_clean_sort,...
                app.proc_SB_clean_sort,...
                app.proc_S_sugar_production,...
                app.proc_SB_recycling,...
                app.proc_SJ_jam_making,...
                app.proc_SJ_jam_making,...
                app.proc_SJ_jam_making,...
                app.proc_FY_making,...
                app.proc_FY_packaging,...
                app.proc_SB_production,...
                app.proc_SJ_jam_making];
            set(text_to_reset,'FontWeight','normal');
            set(text_to_reset,'FontColor','k');
            set(text_to_reset,'FontSize',12);
            app.PD_hide_labels();

            switch app.MachinesListBox.Value
             case "Milk_Skimming"
                 app.PD_plot_yoghurt();
                 app.ProcessListBox.Value = "Yoghurt";
                 text_to_change = app.proc_Y_fat_separation;
             case "Milk_Powder"
                 app.PD_plot_yoghurt();
                 app.ProcessListBox.Value = "Yoghurt";
                 text_to_change = app.proc_Y_spray_drying;
             case "Microbial_Culture"
                 app.PD_plot_yoghurt();
                 app.ProcessListBox.Value = "Yoghurt";
                 text_to_change = app.proc_Y_microbial;
             case "Yogurt_Machine"
                 app.PD_plot_yoghurt();
                 app.ProcessListBox.Value = "Yoghurt";
                 text_to_change = app.proc_Y_production;
             case "Strawberry_Cleaning_Machine"
                 app.PD_plot_strawberry();
                 app.ProcessListBox.Value = "Strawberry";
                 text_to_change = app.proc_SB_clean_sort;
             case "Strawberry_Sorting_Machine"
                 app.PD_plot_strawberry();
                 app.ProcessListBox.Value = "Strawberry";
                 text_to_change = app.proc_SB_clean_sort;
             case "SugarBeet_Cleaning_Machine"
                 app.PD_plot_sugar();
                 app.ProcessListBox.Value = "Sugar";
                 text_to_change = app.proc_S_cleaning_sorting;
             case "SugarBeet_Sorting_Machine"
                 app.PD_plot_sugar();
                 app.ProcessListBox.Value = "Sugar";
                 text_to_change = app.proc_S_cleaning_sorting;
             case "Sugar_Machine"
                 app.PD_plot_sugar();
                 app.ProcessListBox.Value = "Sugar";
                 text_to_change = app.proc_S_sugar_production;
             case "Strawberry_Pulping_Machine"
                 app.PD_plot_strawberry();
                 app.ProcessListBox.Value = "Strawberry";
                 text_to_change = app.proc_S_recycling;
             case "Jam_Blending_Machine"
                 app.PD_plot_strawberry_jam();
                 app.ProcessListBox.Value = "Strawberry Jam";
                 text_to_change = app.proc_SJ_jam_making;
             case "Jam_Evaporator_Machine"
                 app.PD_plot_strawberry_jam();
                 app.ProcessListBox.Value = "Strawberry Jam";
                 text_to_change = app.proc_SJ_jam_making;
             case "Jam_Filling_Machine"
                 app.PD_plot_strawberry_jam();
                 app.ProcessListBox.Value = "Strawberry Jam";
                 text_to_change = app.proc_SJ_jam_making;
                case "Flavour_Yogurt_Machine"
                    app.PD_plot_flavoured_yoghurt();
                    app.ProcessListBox.Value = "Flavoured Yoghurt";
                    text_to_change = app.proc_FY_making;
                case "Flavour_Yogurt_Packing_Machine"
                    app.PD_plot_flavoured_yoghurt();
                    app.ProcessListBox.Value = "Flavoured Yoghurt";
                    text_to_change = app.proc_FY_packaging;
                case "Sugar_Packing"
                    app.PD_plot_sugar();
                    app.ProcessListBox.Value = "Sugar";
                    text_to_change = app.proc_S_packaging;
                case "Jam_Pulping_Machine"
                    app.PD_plot_strawberry_jam();
                    app.ProcessListBox.Value = "Strawberry Jam";
                    text_to_change = app.proc_SJ_jam_making;
                otherwise
                    %fail
            end
            text_to_change.FontWeight = "bold";
            text_to_change.FontColor = "r";
            text_to_change.FontSize = 14;
        end
        
        function results = calculate_machine_stats(app)
            machinechoice = app.machine_database.(app.MachinesListBox.Value).MachineChoice;
            switch machinechoice
                case 1
                    % Average
                    app.multiplier_ProcessRate = 1;
                    app.multiplier_EnergyConsumption = 1;
                    app.multiplier_GasConsumption = 1;
                    app.multiplier_CO2e = 1;
                    app.multiplier_PurchaseCost = 1;
                case 2
                    app.multiplier_ProcessRate = 1;
                    app.multiplier_EnergyConsumption = 1.1;
                    app.multiplier_GasConsumption = 0.9;
                    app.multiplier_CO2e = 0.5;
                    app.multiplier_PurchaseCost = 1.5;
                case 3
                    app.multiplier_ProcessRate = 1;
                    app.multiplier_EnergyConsumption = 0.9;
                    app.multiplier_GasConsumption = 1.1;
                    app.multiplier_CO2e = 1.5;
                    app.multiplier_PurchaseCost = 0.5;
                case 4
                    app.multiplier_ProcessRate = 0.75;
                    app.multiplier_EnergyConsumption = 0.5;
                    app.multiplier_GasConsumption = 1.5;
                    app.multiplier_CO2e = 0.75;
                    app.multiplier_PurchaseCost = 1.25;
                case 5
                    app.multiplier_ProcessRate = 1.25;
                    app.multiplier_EnergyConsumption = 1.5;
                    app.multiplier_GasConsumption = 0.5;
                    app.multiplier_CO2e = 1.25;
                    app.multiplier_PurchaseCost = 0.75;
                otherwise
                    %error
            end
            
        end
        
        function results = vehicle_image_picker(app)

            app.Image_car.Enable = "off";
            app.Image_van.Enable = "off";
            app.Image_hgv.Enable = "off";

            switch app.SizeListBox.Value
                case 'HGV'
            app.Image_hgv.Enable = "on";
                case 'Van'
            app.Image_van.Enable = "on";
                case 'Car'
            app.Image_car.Enable = "on";
                otherwise
                    %fail
            end
        end
        
        function results = level_change(app)
            %%reset all
            %reset machines
            app.machine_database = app.machine_database_original;
            app.MachinesListBox.Value = 'Milk_Skimming';
            app.ProcessRatetonhEditField.Value = app.machine_database.(app.MachinesListBox.Value).ProcessRate;
            app.ElectricityConsumptionMJhEditField.Value = app.machine_database.(app.MachinesListBox.Value).EnergyConsumption;
            app.GasConsumptionMJhEditField.Value = app.machine_database.(app.MachinesListBox.Value).GasConsumption;
            app.MachineSupplierSpinner.Value = app.machine_database.(app.MachinesListBox.Value).MachineChoice;
            %reset final product
            final_product_value_changed(app,1);
            %reset electricity
            app.energy_mix = [41,29,6,18,6];
            app.set_energy_mix();
            %reset transport
            app.Transport = app.Transport_original;
            app.TransportListBox.Value = 'Milk_to_Factory';
            app.DistancekmEditField.Value = app.Transport.(app.TransportListBox.Value).Distance;
            app.SizeListBox.Value = app.Transport.(app.TransportListBox.Value).Object.Vehicle_Size;
            app.TypeListBox.Value = app.Transport.(app.TransportListBox.Value).Object.Vehicle_Type;
            app.FuelListBox.Value = app.Transport.(app.TransportListBox.Value).Object.Fuel_Type_Name;
            %reset hold
            app.transport_results_hold = app.transport_results_original;
            app.report_hold = app.report_original;
            %reset process timing
            app.RawmilkSlider.Value = 9;
            app.YoghurtSlider.Value = 9;
            app.SugarbeetSlider.Value = 9;
            app.SugarSlider.Value = 9;
            app.StrawberriesSlider.Value = 9;
            app.JamSlider.Value = 9;
            app.FlavouredyoghurtSlider.Value = 9;
            app.misc.process_times = [ 9 9 9 9 9 9 9 ]; %7 sliders for start time of processes
            run(app);
            %enable machine & transport selection for viewing stats
            app.MachinesListBox.Enable = 1;
            app.TransportListBox.Enable = 1;
            %remove cop base to trigger base score recalculation ToT
            app.score = rmfield(app.score,'cop_base');
            app.calculate_score;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %check version
            this_version = version('-release');
            % CANCELLED version checking - uni has upgraded to 2024a and it
            % still works <shrug>
            if this_version == '2025a'
                %go ahead
            else
                %no no no
                msgbox(["This app is only compatible with MATLAB 2025a" ;""; "Your version is: MATLAB " + this_version ; "" ; "This app may not function correctly, please start the correct version of MATLAB from the UoS Software Hub."],"Error","error");
                delete(sie_app)
            end

            %level select by date:
            level2_open_date = datetime(2026,02,10);
            level3_open_date = datetime(2026,02,17);
            if datetime('Today') >= level2_open_date
                app.Level2Button.Enable = "on";
                app.Level2Button.Value = true;
            end
            if datetime('Today') >= level3_open_date
                app.Level3Button.Enable = "on";
                app.Level3Button.Value = true;
            else
                app.Level1Button.Enable = "on";
                app.Level1Button.Value = true;
            end

            %OOF this all needs refactoring
            if app.Level1Button.Value == true  %where "Button1" is the name of the first radio button
                %level 1 electricity
                setEnableWithChildren(app, app.MachinesTab, 0)
                setEnableWithChildren(app, app.TransportTab, 0)
                setEnableWithChildren(app, app.TimingTab, 0)
            elseif app.Level2Button.Value == true
                %level 2 electricity+machines
                setEnableWithChildren(app, app.MachinesTab, 1)
                setEnableWithChildren(app, app.TransportTab, 0)
                setEnableWithChildren(app, app.TimingTab, 0)
            elseif app.Level3Button.Value == true
                %level 3 electricity+machines+transport+timing
                setEnableWithChildren(app, app.MachinesTab, 1)
                setEnableWithChildren(app, app.TransportTab, 1)
                setEnableWithChildren(app, app.TimingTab, 0)
            else
                %error
            end
            %level_change(app);
            %enable machine & transport selection for viewing stats
            app.MachinesListBox.Enable = 1;
            app.TransportListBox.Enable = 1;
            
            % load databases
            % UNSTRUCT THE STRUCTS?
            % tables get turned into structs somehow by "load"
            app.chemical_database = app.chemical_database.chemical_database;
            app.machine_database = app.machine_database.machine_database;
            app.Transport = app.Transport.Transport;
            %simplify transport list
            %app.Transport(4:6,:) = [];          
            %misc initial settings
            app.misc.hydrogen = "grey";
            app.GreyButton.Value = true;
            app.misc.top_up_demand = "gas";
            app.misc.final_product = 1;
            app.misc.season = "Summer";
            app.misc.gas_composition = 0;
            app.misc.process_times = [ 9 9 9 9 9 9 9 ]; %7 sliders for start time of processes
            app.misc.load_multiplier = 1;
            %make a copy of machine database & transport for resetting values
            app.machine_database_original = app.machine_database;
            app.Transport_original = app.Transport;
            % preserve axes on bar graphs
            app.UIAxes_energy.PositionConstraint = "innerposition";
            app.UIAxes_exergy.PositionConstraint = "innerposition";
            % run sie_app_main with standard data to populate graphs initially
            [app.transport_totals, app.transport_results, app.report, app.machine_database, app.Grid_Composition, app.FC_data, app.process_timing_results] =...
                sie_app_main(app.energy_mix,app.machine_database,app.chemical_database,app.Transport,app.misc);
            % set hold values to initial values
            app.transport_results_hold = app.transport_results;
            app.transport_results_original = app.transport_results;
            app.report_hold = app.report;
            app.report_original = app.report;
            app.transport_totals_hold = app.transport_totals;
            % populate bar graph drop down menus with data from report
            fns_process = fieldnames(app.report);
            app.ProcessDropDown.Items = fns_process;
            %app.ProcessDropDown.Value = 'Raw Milk Production';
            draw_bar_graphs(app);
            % populate machine database listbox
            app.fns = fieldnames(app.machine_database);
            app.MachinesListBox.Items = app.fns;
            app.MachinesListBox.Value = app.fns(1);
            load_machine(app);
            % populate transport list box
            % app.fns = fieldnames(app.Transport);
            % % app.TransportListBox.Items = app.fns;
            % % app.TransportListBox.Value = app.fns(1);
            % Simplified list:
            app.TransportListBox.Items = [{'Milk_to_Factory'};{'SugarBeet_to_SugarFactory'};{'Strawberry_to_JamFactory'};{'Distribution_to_Shop'}];
            app.TransportListBox.Value = {'Milk_to_Factory'};

            app.SizeListBox.Items = {'HGV','Van','Car'};
            app.TypeListBox.Items = {'ICE','EV'};
            %Remove hydrogen as it is problematic...
            %app.FuelListBox.Items = {'Diesel','Petrol','LNG','Hydrogen'};
            app.FuelListBox.Items = {'Diesel','Petrol','LNG'};
            app.DistancekmEditField.Value = app.Transport.(app.TransportListBox.Value).Distance;
            app.SizeListBox.Value = app.Transport.(app.TransportListBox.Value).Object.Vehicle_Size;
            app.TypeListBox.Value = app.Transport.(app.TransportListBox.Value).Object.Vehicle_Type;
            app.FuelListBox.Value = app.Transport.(app.TransportListBox.Value).Object.Fuel_Type_Name;
            %set initial energy mix
            set_energy_mix(app);
            %draw machine properties graph
            app.draw_machine_statistics();
            %update initial transport results
            draw_transport_results(app);
            PD_plot_sugar(app);
            draw_grid_composition(app);
            draw_process_timings(app);
            draw_summary(app);
            calculate_score(app);
            %turn off interactivity of graphs as it's buggy
            h = findobj(app.H7136SustainabilityinengineeringappUIFigure,'Type','axes');
            for i = 1: numel(h)
                %disableDefaultInteractivity(h(i));
                h(i).Toolbar.Visible = 'off';
            end
            % 
            % %set level 2
            % setEnableWithChildren(app, app.MachinesTab, 1);
            % setEnableWithChildren(app, app.TransportTab, 1);
            % setEnableWithChildren(app, app.TimingTab, 0);
            % app.MachinesListBox.Enable = 1;
            % app.TransportListBox.Enable = 1;
            %set open tabs for debugging
            app.TabGroup.SelectedTab = app.ElectricityTab;
            app.TabGroup2.SelectedTab = app.SummaryTab;
            vehicle_image_picker(app);
        end

        % Value changed function: MachinesListBox
        function MachinesListBoxValueChanged(app, event)
            %load new machine_database entry
            %update machine stat display information
            app.emissionmultiplierEditField.Value = app.machine_database.(app.MachinesListBox.Value).CO2e;
            app.PurchaseCostEditField.Value = app.machine_database.(app.MachinesListBox.Value).PurchaseCost;
            app.ProcessRatetonhEditField.Value = app.machine_database.(app.MachinesListBox.Value).ProcessRate;
            app.ElectricityConsumptionMJhEditField.Value = app.machine_database.(app.MachinesListBox.Value).EnergyConsumption;
            app.GasConsumptionMJhEditField.Value = app.machine_database.(app.MachinesListBox.Value).GasConsumption;
            app.MachineQuantityEditField.Value =  app.machine_database.(app.MachinesListBox.Value).MachineQuantity;
            app.MachineSupplierSpinner.Value =  app.machine_database.(app.MachinesListBox.Value).MachineChoice;
            %load_machine(app);
            draw_machine_statistics(app);
            %run(app);

        end

        % Value changed function: MachineSupplierSpinner
        function MachineSupplierSpinnerValueChanged(app, event)
            value = app.MachineSupplierSpinner.Value;
            app.machine_database.(app.MachinesListBox.Value).MachineChoice = value;
            load_machine(app);
            draw_machine_statistics(app);
            run(app);
        end

        % Value changed function: MachineQuantityEditField
        function MachineQuantityEditFieldValueChanged(app, event)
            value = app.MachineQuantityEditField.Value;
            app.machine_database.(app.MachinesListBox.Value).MachineQuantity = value;
            load_machine(app); %Maybe doesn't work?
            draw_machine_statistics(app);
            run(app);
        end

        % Button pushed function: UpdateIndividualMachineButton
        function UpdateIndividualMachineButtonPushed(app, event)
            app.machine_database.(app.MachinesListBox.Value).CO2e = app.emissionmultiplierEditField.Value;
            app.machine_database.(app.MachinesListBox.Value).ProcessRate = app.ProcessRatetonhEditField.Value;
            app.machine_database.(app.MachinesListBox.Value).GasConsumption = app.GasConsumptionMJhEditField.Value;
            app.machine_database.(app.MachinesListBox.Value).PurchaseCost = app.PurchaseCostEditField.Value;
            app.machine_database.(app.MachinesListBox.Value).EnergyConsumption = app.ElectricityConsumptionMJhEditField.Value;
            %load_machine(app); %Maybe doesn't work?
            draw_machine_statistics(app);
            run(app);
        end

        % Value changed function: emissionmultiplierEditField
        function emissionmultiplierEditFieldValueChanged(app, event)
            % value = app.emissionmultiplierEditField.Value;
            % app.machine_database.(app.MachinesListBox.Value).CO2e = value;
            % load_machine(app); %Maybe doesn't work?
            % draw_machine_statistics(app);
            % run(app);
        end

        % Value changed function: ProcessRatetonhEditField
        function ProcessRatetonhEditFieldValueChanged(app, event)
            % value = app.ProcessRatetonhEditField.Value;
            % app.machine_database.(app.MachinesListBox.Value).ProcessRate = value;
            % load_machine(app); %Maybe doesn't work?
            % draw_machine_statistics(app);
            % run(app);
        end

        % Value changed function: GasConsumptionMJhEditField
        function GasConsumptionMJhEditFieldValueChanged(app, event)
            % value = app.GasConsumptionMJhEditField.Value;
            % app.machine_database.(app.MachinesListBox.Value).GasConsumption = value;
            % load_machine(app); %Maybe doesn't work?
            % draw_machine_statistics(app);
            % run(app);
        end

        % Value changed function: PurchaseCostEditField
        function PurchaseCostEditFieldValueChanged(app, event)
            % value = app.PurchaseCostEditField.Value;
            % app.machine_database.(app.MachinesListBox.Value).PurchaseCost = value;
            % load_machine(app); %Maybe doesn't work?
            % draw_machine_statistics(app);
            % run(app);
        end

        % Value changed function: ElectricityConsumptionMJhEditField
        function ElectricityConsumptionMJhEditFieldValueChanged(app, event)
            % value = app.ElectricityConsumptionMJhEditField.Value;
            % app.machine_database.(app.MachinesListBox.Value).EnergyConsumption = value;
            % load_machine(app); %Maybe doesn't work?
            % draw_machine_statistics(app);
            % run(app);
        end

        % Value changed function: EnergyMixGas
        function EnergyMixGasValueChanged(app, event)
            value = app.EnergyMixGas.Value;
            balance_energy_mix(app,1,value);
        end

        % Value changed function: EnergyMixWind
        function EnergyMixWindValueChanged(app, event)
            value = app.EnergyMixWind.Value;
            balance_energy_mix(app,2,value);
        end

        % Value changed function: EnergyMixCoal
        function EnergyMixCoalValueChanged(app, event)
            value = app.EnergyMixCoal.Value;
            balance_energy_mix(app,3,value);
        end

        % Value changed function: EnergyMixNuclear
        function EnergyMixNuclearValueChanged(app, event)
            value = app.EnergyMixNuclear.Value;
            balance_energy_mix(app,4,value);
        end

        % Value changed function: EnergyMixSolar
        function EnergyMixSolarValueChanged(app, event)
            value = app.EnergyMixSolar.Value;
            balance_energy_mix(app,5,value);
        end

        % Value changed function: TransportListBox
        function TransportListBoxValueChanged(app, event)
            app.DistancekmEditField.Value = app.Transport.(app.TransportListBox.Value).Distance;
            app.SizeListBox.Value = app.Transport.(app.TransportListBox.Value).Object.Vehicle_Size;
            app.TypeListBox.Value = app.Transport.(app.TransportListBox.Value).Object.Vehicle_Type;
            app.FuelListBox.Value = app.Transport.(app.TransportListBox.Value).Object.Fuel_Type_Name;
            vehicle_image_picker(app);
            draw_transport_results(app);
        end

        % Value changed function: SizeListBox
        function SizeListBoxValueChanged(app, event)
            value = app.SizeListBox.Value;
            app.Transport.(app.TransportListBox.Value).Object.Vehicle_Size = value;
            if app.trans_all_btn.Value == true
                fields = fieldnames(app.Transport);
                for i = 1 : numel(fields)
                    app.Transport.(fields{i}).Object.Vehicle_Size = value;
                end
            end
            vehicle_image_picker(app);
            run(app);
        end

        % Value changed function: TypeListBox
        function TypeListBoxValueChanged(app, event)
            value = app.TypeListBox.Value;
            app.Transport.(app.TransportListBox.Value).Object.Vehicle_Type = value;
            if app.trans_all_btn.Value == true
                fields = fieldnames(app.Transport);
                for i = 1 : numel(fields)
                    app.Transport.Milk_to_Factory.Object.Vehicle_Type = value;
                    app.Transport.Strawberry_to_JamFactory.Object.Vehicle_Type = value;
                    app.Transport.SugarBeet_to_SugarFactory.Object.Vehicle_Type = value;
                    app.Transport.Sugar_to_JamFactory.Object.Vehicle_Type = value;
                    app.Transport.Jam_to_YogurtFactory.Object.Vehicle_Type = value;
                    app.Transport.Yogurt_to_Distribution.Object.Vehicle_Type = value;
                    app.Transport.Distribution_to_Shop.Object.Vehicle_Type = value;

                end
            end

            run(app);
        end

        % Value changed function: DistancekmEditField
        function DistancekmEditFieldValueChanged(app, event)
            value = app.DistancekmEditField.Value;
            app.Transport.(app.TransportListBox.Value).Distance = value;
            app.Transport.(app.TransportListBox.Value).Object.Distance = value;
            if app.trans_all_btn.Value == true
                fields = fieldnames(app.Transport);
                for i = 1 : numel(fields)
                    app.Transport.(fields{i}).Distance = value;
                    app.Transport.(fields{i}).Object.Distance = value;
                end
            end
            run(app);
        end

        % Value changed function: FuelListBox
        function FuelListBoxValueChanged(app, event)
            value = app.FuelListBox.Value;
            app.Transport.(app.TransportListBox.Value).Object.Fuel_Type_Name = value;
            if app.trans_all_btn.Value == true
                fields = fieldnames(app.Transport);
                for i = 1 : numel(fields)
                    app.Transport.(fields{i}).Object.Fuel_Type_Name = value;
                end
            end
            run(app);
        end

        % Value changed function: ProcessDropDown
        function ProcessDropDownValueChanged(app, event)
            draw_bar_graphs(app);
        end

        % Button pushed function: ResetMachinesButton
        function ResetMachinesButtonPushed(app, event)
            app.machine_database = app.machine_database_original;
            app.MachinesListBox.Value = 'Milk_Skimming';
            app.ProcessRatetonhEditField.Value = app.machine_database.(app.MachinesListBox.Value).ProcessRate;
            app.ElectricityConsumptionMJhEditField.Value = app.machine_database.(app.MachinesListBox.Value).EnergyConsumption;
            app.GasConsumptionMJhEditField.Value = app.machine_database.(app.MachinesListBox.Value).GasConsumption;
            app.MachineSupplierSpinner.Value = app.machine_database.(app.MachinesListBox.Value).MachineChoice;
            app.MachineQuantityEditField.Value = 1;
            app.PurchaseCostEditField.Value = app.machine_database.(app.MachinesListBox.Value).PurchaseCost;
            app.emissionmultiplierEditField.Value = app.machine_database.(app.MachinesListBox.Value).CO2e;
            draw_bar_graphs(app);
            draw_machine_statistics(app);
            run(app);
        end

        % Button pushed function: ResetFinalProductButton
        function ResetFinalProductButtonPushed(app, event)
            final_product_value_changed(app,1);
        end

        % Button pushed function: ResetElectricityButton
        function ResetElectricityButtonPushed(app, event)
            app.do_not_run_program = 1;
            app.energy_mix = [41,29,6,18,6];
            app.EnergyMixGas.Value = 41;
            app.EnergyMixWind.Value = 35;
            app.EnergyMixNuclear.Value = 18;
            app.EnergyMixSolar.Value = 6;
            %top up
            app.SeasonDropDown.Value = 'Summer';
            app.misc.season = app.SeasonDropDown.Value;
            %season
            app.GasButton.Value = true;
            app.misc.top_up_demand = "gas";
            app.do_not_run_program = 0;
            run(app);
        end

        % Button pushed function: ResetTransportButton
        function ResetTransportButtonPushed(app, event)
            app.Transport = app.Transport_original;
            app.TransportListBox.Value = 'Milk_to_Factory';
            app.DistancekmEditField.Value = app.Transport.(app.TransportListBox.Value).Distance;
            app.SizeListBox.Value = app.Transport.(app.TransportListBox.Value).Object.Vehicle_Size;
            app.TypeListBox.Value = app.Transport.(app.TransportListBox.Value).Object.Vehicle_Type;
            app.FuelListBox.Value = app.Transport.(app.TransportListBox.Value).Object.Fuel_Type_Name;
            app.misc.load_multiplier = 1;
            app.processloadmultiplierEditField.Value = 1;
            run(app);
        end

        % Button pushed function: ResetHOLDButton
        function ResetHOLDButtonPushed(app, event)
            app.transport_results_hold = app.transport_results_original;
            app.report_hold = app.report_original;
            run(app);
        end

        % Button pushed function: HOLDButton
        function HOLDButtonPushed(app, event)
            app.transport_results_hold = app.transport_results;
            app.transport_totals_hold = app.transport_totals;
            app.report_hold = app.report;
            run(app);
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: ProcessListBox
        function ProcessListBoxValueChanged(app, event)
            draw_process_diagram(app);
        end

        % Value changed function: SeasonDropDown
        function SeasonDropDownValueChanged(app, event)
            app.misc.season = app.SeasonDropDown.Value;
            run(app);
        end

        % Value changed function: GasComposition
        function GasCompositionValueChanged(app, event)
            value = app.GasComposition.Value;
            if value > 0
                app.misc.gas_composition = value/100;
            else
                app.misc.gas_composition = 0;
            end
            run(app);
        end

        % Value changed function: final_product_Slider
        function final_product_SliderValueChanged(app, event)
            value = app.final_product_Slider.Value;
            final_product_value_changed(app,value);
        end

        % Value changed function: final_product_numericalEditField_2
        function final_product_numericalEditField_2ValueChanged(app, event)
            value = app.final_product_numericalEditField_2.Value;
            final_product_value_changed(app,value);
        end

        % Value changed function: RawmilkSlider
        function RawmilkSliderValueChanged(app, event)
            value = app.RawmilkSlider.Value;
            app.misc.process_times(1) = value;
            run(app);
        end

        % Value changed function: YoghurtSlider
        function YoghurtSliderValueChanged(app, event)
            value = app.YoghurtSlider.Value;
            app.misc.process_times(2) = value;
            run(app);
        end

        % Value changed function: SugarbeetSlider
        function SugarbeetSliderValueChanged(app, event)
            value = app.SugarbeetSlider.Value;
            app.misc.process_times(3) = value;
            run(app);
        end

        % Value changed function: SugarSlider
        function SugarSliderValueChanged(app, event)
            value = app.SugarSlider.Value;
            app.misc.process_times(4) = value;
            run(app);
        end

        % Value changed function: StrawberriesSlider
        function StrawberriesSliderValueChanged(app, event)
            value = app.StrawberriesSlider.Value;
            app.misc.process_times(5) = value;
            run(app);
        end

        % Value changed function: JamSlider
        function JamSliderValueChanged(app, event)
            value = app.JamSlider.Value;
            app.misc.process_times(6) = value;
            run(app);
        end

        % Value changed function: FlavouredyoghurtSlider
        function FlavouredyoghurtSliderValueChanged(app, event)
            value = app.FlavouredyoghurtSlider.Value;
            app.misc.process_times(7) = value;
            run(app);
        end

        % Button pushed function: ResetProcesstimingButton
        function ResetProcesstimingButtonPushed(app, event)
            app.RawmilkSlider.Value = 9;
            app.YoghurtSlider.Value = 9;
            app.SugarbeetSlider.Value = 9;
            app.SugarSlider.Value = 9;
            app.StrawberriesSlider.Value = 9;
            app.JamSlider.Value = 9;
            app.FlavouredyoghurtSlider.Value = 9;

            app.misc.process_times = [ 9 9 9 9 9 9 9 ]; %7 sliders for start time of processes
            run(app);
        end

        % Selection changed function: DifficultyButtonGroup
        function DifficultyButtonGroupSelectionChanged(app, event)
            %OOF this all needs refactoring
            if app.Level1Button.Value == true  %where "Button1" is the name of the first radio button
                %level 1 electricity
                setEnableWithChildren(app, app.MachinesTab, 0)
                setEnableWithChildren(app, app.TransportTab, 0)
                setEnableWithChildren(app, app.TimingTab, 0)
            elseif app.Level2Button.Value == true
                %level 2 electricity+machines
                setEnableWithChildren(app, app.MachinesTab, 1)
                setEnableWithChildren(app, app.TransportTab, 0)
                setEnableWithChildren(app, app.TimingTab, 0)
            elseif app.Level3Button.Value == true
                %level 3 electricity+machines+transport+timing
                setEnableWithChildren(app, app.MachinesTab, 1)
                setEnableWithChildren(app, app.TransportTab, 1)
                setEnableWithChildren(app, app.TimingTab, 0)
            else
                %error
            end
            level_change(app);
        end

        % Selection changed function: HydrogentypeButtonGroup
        function HydrogentypeButtonGroupSelectionChanged(app, event)
            if app.GreyButton.Value   %where "Button1" is the name of the first radio button
                app.misc.hydrogen = "grey";
            elseif app.BlueButton.Value
                app.misc.hydrogen = "blue";
            elseif app.GreenButton.Value
                app.misc.hydrogen = "green";
            else
                %error
            end
            run(app);
        end

        % Selection changed function: TopupdemandButtonGroup
        function TopupdemandButtonGroupSelectionChanged(app, event)
            if app.CoalButton.Value   %where "Button1" is the name of the first radio button
                %gas
                app.misc.top_up_demand = "coal";
            elseif app.GasButton.Value
                %coal
                app.misc.top_up_demand = "gas";
            else
                %error
            end
            run(app);
        end

        % Button pushed function: manual_setbutton
        function manual_setbuttonButtonPushed(app, event)
            total = app.man_gas.Value + app.man_wind.Value + app.man_coal.Value + app.man_nuclear.Value + app.man_solar.Value;
            if (total > 100)
                warndlg('energy mix > 100%, try again','User error');
            elseif (total <= 100)
                app.energy_mix(1) = app.man_gas.Value;
                app.energy_mix(2) = app.man_wind.Value;
                app.energy_mix(3) = app.man_coal.Value;
                app.energy_mix(4) = app.man_nuclear.Value;
                app.energy_mix(5) = app.man_solar.Value;
                deficit = 100 - total;
                if app.CoalButton.Value
                    app.energy_mix(3) = app.energy_mix(3) + deficit;
                    warndlg('energy mix < 100%, rebalanced using coal','User error');
                elseif app.GasButton.Value
                    app.energy_mix(1) = app.energy_mix(1) + deficit;
                    warndlg('energy mix < 100%, rebalanced using gas','User error');
                else
                    %fail
                end
            end
            app.set_energy_mix();
            run(app);
        end

        % Button pushed function: OUTPUTSCOREButton
        function OUTPUTSCOREButtonPushed(app, event)
                prompt = {'Enter your candidate number:'};
                dlgtitle = 'Score Output';
                fieldsize = [1 50];
                definput = {'<cand#>'};
                candno = inputdlg(prompt,dlgtitle,fieldsize,definput);
                str1 = candno{1,1};
                str2 = ".score";
                str3 = strcat(str1,str2);
                energy_mix = app.energy_mix;
                machine_database = app.machine_database;
                chemical_database = app.chemical_database;
                Transport = app.Transport;
                misc = app.misc;
                score = [app.score.cop, app.score.co2e, app.score.machine];
                str2 = candno{1,1};
                [file,location,indx] = uiputfile(str3);
                f = fullfile(location,file); 
                save(f,"candno","energy_mix","machine_database","chemical_database","Transport","misc","score");
                fileID = fopen(f);
                cs = fread(fileID);
                save(f,"cs","candno","energy_mix","machine_database","chemical_database","Transport","misc","score");
                fclose(fileID);

        end

        % Button pushed function: FindButton
        function FindButtonPushed(app, event)
          identify_machine_process(app);
          if app.TabGroup2.SelectedTab ~= app.ProcessdiagramsTab
          app.TabGroup2.SelectedTab = app.ProcessdiagramsTab;
          end
        end

        % Value changed function: processloadmultiplierEditField
        function processloadmultiplierEditFieldValueChanged(app, event)
            value = app.processloadmultiplierEditField.Value;
            app.misc.load_multiplier = value;
            run(app);
        end

        % Value changed function: dist_cow_fact
        function dist_cow_factValueChanged(app, event)
            value = app.dist_cow_fact.Value;
            app.Transport.Milk_to_Factory.Distance = value;
            app.Transport.Milk_to_Factory.Object.Distance = value;
            run(app);
        end

        % Value changed function: dist_beet_fact
        function dist_beet_factValueChanged(app, event)
            value = app.dist_beet_fact.Value;
            app.Transport.SugarBeet_to_SugarFactory.Distance = value;
            app.Transport.SugarBeet_to_SugarFactory.Object.Distance = value;
            run(app);
        end

        % Value changed function: dist_stra_fact
        function dist_stra_factValueChanged(app, event)
            value = app.dist_stra_fact.Value;
            app.Transport.Strawberry_to_JamFactory.Distance = value;
            app.Transport.Strawberry_to_JamFactory.Object.Distance = value;
            run(app);
        end

        % Value changed function: dist_fact_shop
        function dist_fact_shopValueChanged(app, event)
            value = app.dist_fact_shop.Value;
            app.Transport.Distribution_to_Shop.Distance = value;
            app.Transport.Distribution_to_Shop.Object.Distance = value;
            run(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.H7136SustainabilityinengineeringappUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {980, 980, 980};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {980, 980};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {436, '1x', 170};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.H7136SustainabilityinengineeringappUIFigure = uifigure('Visible', 'off');
            app.H7136SustainabilityinengineeringappUIFigure.Name = 'H7136 Sustainability in engineering app';
            app.H7136SustainabilityinengineeringappUIFigure.Icon = fullfile(pathToMLAPP, 'res', 'logo.png');
            app.H7136SustainabilityinengineeringappUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.H7136SustainabilityinengineeringappUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ElectricityTab = uitab(app.TabGroup);
            app.ElectricityTab.Title = 'Electricity';
            app.GridPlot = uiaxes(app.ElectricityTab);
            xlabel(app.GridPlot, 'Time, min')
            ylabel(app.GridPlot, '%')
            zlabel(app.GridPlot, 'Z')
            app.GridPlot.XTick = [];
            app.GridPlot.YTick = [];
            app.GridPlot.YTickLabel = '';
            app.WindLabel = uilabel(app.ElectricityTab);
            app.WindLabel.Text = 'Wind';
            app.WindLabel_2 = uilabel(app.ElectricityTab);
            app.WindLabel_2.Text = 'Solar';
            app.EnergyMixSolar = uislider(app.ElectricityTab);
            app.EnergyMixSolar.ValueChangedFcn = createCallbackFcn(app, @EnergyMixSolarValueChanged, true);
            app.EnergyMixSolar.Value = 40.6;
            app.WindLabel_5 = uilabel(app.ElectricityTab);
            app.WindLabel_5.Text = 'Nuclear';
            app.EnergyMixNuclear = uislider(app.ElectricityTab);
            app.EnergyMixNuclear.ValueChangedFcn = createCallbackFcn(app, @EnergyMixNuclearValueChanged, true);
            app.EnergyMixNuclear.Value = 40.6;
            app.EnergyMixWind = uislider(app.ElectricityTab);
            app.EnergyMixWind.ValueChangedFcn = createCallbackFcn(app, @EnergyMixWindValueChanged, true);
            app.EnergyMixWind.Value = 40.6;
            app.EnergyMixCoal = uislider(app.ElectricityTab);
            app.EnergyMixCoal.ValueChangedFcn = createCallbackFcn(app, @EnergyMixCoalValueChanged, true);
            app.EnergyMixCoal.Value = 40.6;
            app.WindLabel_6 = uilabel(app.ElectricityTab);
            app.WindLabel_6.Text = 'Gas';
            app.EnergyMixGas = uislider(app.ElectricityTab);
            app.EnergyMixGas.ValueChangedFcn = createCallbackFcn(app, @EnergyMixGasValueChanged, true);
            app.EnergyMixGas.Value = 40.6;
            app.GridMakeupLabel = uilabel(app.ElectricityTab);
            app.GridMakeupLabel.Text = 'Grid Makeup:';
            app.SeasonDropDownLabel = uilabel(app.ElectricityTab);
            app.SeasonDropDownLabel.Text = 'Season';
            app.SeasonDropDown = uidropdown(app.ElectricityTab);
            app.SeasonDropDown.Items = {'Summer', 'Spring', 'Autumn', 'Winter'};
            app.SeasonDropDown.ValueChangedFcn = createCallbackFcn(app, @SeasonDropDownValueChanged, true);
            app.SeasonDropDown.Value = 'Summer';
            app.WindLabel_7 = uilabel(app.ElectricityTab);
            app.WindLabel_7.Text = 'Coal';
            app.TopupdemandButtonGroup = uibuttongroup(app.ElectricityTab);
            app.TopupdemandButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TopupdemandButtonGroupSelectionChanged, true);
            app.TopupdemandButtonGroup.Title = 'Top up demand:';
            app.GasButton = uiradiobutton(app.TopupdemandButtonGroup);
            app.GasButton.Text = 'Gas';
            app.GasButton.Value = true;
            app.CoalButton = uiradiobutton(app.TopupdemandButtonGroup);
            app.CoalButton.Text = 'Coal';
            app.ManualPanel = uipanel(app.ElectricityTab);
            app.ManualPanel.Title = 'Manual';
            app.man_gas = uieditfield(app.ManualPanel, 'numeric');
            app.man_gas.Limits = [0 100];
            app.man_gas.ValueDisplayFormat = '%.1f';
            app.man_solar = uieditfield(app.ManualPanel, 'numeric');
            app.man_solar.Limits = [0 100];
            app.man_solar.ValueDisplayFormat = '%.1f';
            app.man_wind = uieditfield(app.ManualPanel, 'numeric');
            app.man_wind.Limits = [0 100];
            app.man_wind.ValueDisplayFormat = '%.1f';
            app.man_coal = uieditfield(app.ManualPanel, 'numeric');
            app.man_coal.Limits = [0 100];
            app.man_coal.ValueDisplayFormat = '%.1f';
            app.man_nuclear = uieditfield(app.ManualPanel, 'numeric');
            app.man_nuclear.Limits = [0 100];
            app.man_nuclear.ValueDisplayFormat = '%.1f';
            app.manual_setbutton = uibutton(app.ManualPanel, 'push');
            app.manual_setbutton.ButtonPushedFcn = createCallbackFcn(app, @manual_setbuttonButtonPushed, true);
            app.manual_setbutton.Text = 'Set';
            app.GridmakeupLabel = uilabel(app.ElectricityTab);
            app.GridmakeupLabel.Text = 'Grid makeup: (%) ';
            app.MachinesTab = uitab(app.TabGroup);
            app.MachinesTab.Title = 'Machines';
            app.UIAxes_machines = uiaxes(app.MachinesTab);
            xlabel(app.UIAxes_machines, 'X')
            ylabel(app.UIAxes_machines, 'Y')
            zlabel(app.UIAxes_machines, 'Z')
            app.SelectedmachineLabel = uilabel(app.MachinesTab);
            app.SelectedmachineLabel.Text = 'Selected machine:';
            app.FindButton = uibutton(app.MachinesTab, 'push');
            app.FindButton.ButtonPushedFcn = createCallbackFcn(app, @FindButtonPushed, true);
            app.FindButton.Text = 'Find';
            app.Panel = uipanel(app.MachinesTab);
            app.machine_EditmodeButtonGroup = uibuttongroup(app.Panel);
            app.machine_EditmodeButtonGroup.Title = 'Edit mode:';
            app.machine_ind_btn = uiradiobutton(app.machine_EditmodeButtonGroup);
            app.machine_ind_btn.Text = 'Individual';
            app.machine_ind_btn.Value = true;
            app.machine_all_btn = uiradiobutton(app.machine_EditmodeButtonGroup);
            app.machine_all_btn.Text = 'All';
            app.MachineSupplierSpinnerLabel = uilabel(app.Panel);
            app.MachineSupplierSpinnerLabel.Text = 'Machine Supplier';
            app.MachineSupplierSpinner = uispinner(app.Panel);
            app.MachineSupplierSpinner.Limits = [1 5];
            app.MachineSupplierSpinner.ValueChangedFcn = createCallbackFcn(app, @MachineSupplierSpinnerValueChanged, true);
            app.MachineSupplierSpinner.Value = 1;
            app.MachineQuantityLabel = uilabel(app.Panel);
            app.MachineQuantityLabel.Enable = 'off';
            app.MachineQuantityLabel.Text = 'Machine Quantity';
            app.MachineQuantityEditField = uieditfield(app.Panel, 'numeric');
            app.MachineQuantityEditField.Limits = [1 1000];
            app.MachineQuantityEditField.ValueChangedFcn = createCallbackFcn(app, @MachineQuantityEditFieldValueChanged, true);
            app.MachineQuantityEditField.Editable = 'off';
            app.MachineQuantityEditField.Enable = 'off';
            app.MachineQuantityEditField.Value = 1;
            app.Panel_2 = uipanel(app.MachinesTab);
            app.emissionefficiencyLabel = uilabel(app.Panel_2);
            app.emissionefficiencyLabel.Text = 'emission multiplier';
            app.emissionmultiplierEditField = uieditfield(app.Panel_2, 'numeric');
            app.emissionmultiplierEditField.ValueDisplayFormat = '%3.2f';
            app.emissionmultiplierEditField.ValueChangedFcn = createCallbackFcn(app, @emissionmultiplierEditFieldValueChanged, true);
            app.PurchaseCostLabel = uilabel(app.Panel_2);
            app.PurchaseCostLabel.Text = 'PurchaseCost, £';
            app.PurchaseCostEditField = uieditfield(app.Panel_2, 'numeric');
            app.PurchaseCostEditField.ValueDisplayFormat = '%3.2f';
            app.PurchaseCostEditField.ValueChangedFcn = createCallbackFcn(app, @PurchaseCostEditFieldValueChanged, true);
            app.ProcessRatetonhEditFieldLabel = uilabel(app.Panel_2);
            app.ProcessRatetonhEditFieldLabel.Text = 'ProcessRate, ton/h';
            app.ProcessRatetonhEditField = uieditfield(app.Panel_2, 'numeric');
            app.ProcessRatetonhEditField.ValueDisplayFormat = '%3.2f';
            app.ProcessRatetonhEditField.ValueChangedFcn = createCallbackFcn(app, @ProcessRatetonhEditFieldValueChanged, true);
            app.ElectricityConsumptionMJhEditFieldLabel = uilabel(app.Panel_2);
            app.ElectricityConsumptionMJhEditFieldLabel.Text = 'ElectricityConsumption, MJ/h';
            app.ElectricityConsumptionMJhEditField = uieditfield(app.Panel_2, 'numeric');
            app.ElectricityConsumptionMJhEditField.ValueDisplayFormat = '%3.2f';
            app.ElectricityConsumptionMJhEditField.ValueChangedFcn = createCallbackFcn(app, @ElectricityConsumptionMJhEditFieldValueChanged, true);
            app.GasConsumptionMJhEditFieldLabel = uilabel(app.Panel_2);
            app.GasConsumptionMJhEditFieldLabel.Text = 'GasConsumption, MJ/h';
            app.GasConsumptionMJhEditField = uieditfield(app.Panel_2, 'numeric');
            app.GasConsumptionMJhEditField.ValueDisplayFormat = '%3.2f';
            app.GasConsumptionMJhEditField.ValueChangedFcn = createCallbackFcn(app, @GasConsumptionMJhEditFieldValueChanged, true);
            app.EditindividualmachineLabel = uilabel(app.Panel_2);
            app.EditindividualmachineLabel.Text = 'Edit individual machine:';
            app.machine_name_label = uilabel(app.Panel_2);
            app.UpdateIndividualMachineButton = uibutton(app.Panel_2, 'push');
            app.UpdateIndividualMachineButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateIndividualMachineButtonPushed, true);
            app.UpdateIndividualMachineButton.Text = 'Update';
            app.GascompositionPanel = uipanel(app.MachinesTab);
            app.GascompositionPanel.Title = 'Gas composition';
            app.GascompositionPanel.Visible = 'off';
            app.WindLabel_9 = uilabel(app.GascompositionPanel);
            app.WindLabel_9.Text = 'Hydrogen';
            app.GasComposition = uislider(app.GascompositionPanel);
            app.GasComposition.ValueChangedFcn = createCallbackFcn(app, @GasCompositionValueChanged, true);
            app.WindLabel_10 = uilabel(app.GascompositionPanel);
            app.WindLabel_10.Text = 'Natural gas';
            app.HydrogentypeButtonGroup = uibuttongroup(app.GascompositionPanel);
            app.HydrogentypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @HydrogentypeButtonGroupSelectionChanged, true);
            app.HydrogentypeButtonGroup.Title = 'Hydrogen type:';
            app.GreenButton = uiradiobutton(app.HydrogentypeButtonGroup);
            app.GreenButton.Text = 'Green';
            app.BlueButton = uiradiobutton(app.HydrogentypeButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.BlueButton.Value = true;
            app.GreyButton = uiradiobutton(app.HydrogentypeButtonGroup);
            app.GreyButton.Text = 'Grey';
            app.MachinesListBox = uilistbox(app.MachinesTab);
            app.MachinesListBox.ValueChangedFcn = createCallbackFcn(app, @MachinesListBoxValueChanged, true);
            app.TransportTab = uitab(app.TabGroup);
            app.TransportTab.Title = 'Transport';
            app.DistancekmEditFieldLabel = uilabel(app.TransportTab);
            app.DistancekmEditFieldLabel.Text = 'Distance, km';
            app.DistancekmEditField = uieditfield(app.TransportTab, 'numeric');
            app.DistancekmEditField.ValueDisplayFormat = '%.0f';
            app.DistancekmEditField.ValueChangedFcn = createCallbackFcn(app, @DistancekmEditFieldValueChanged, true);
            app.FuelListBoxLabel = uilabel(app.TransportTab);
            app.FuelListBoxLabel.Text = 'Fuel';
            app.FuelListBox = uilistbox(app.TransportTab);
            app.FuelListBox.ValueChangedFcn = createCallbackFcn(app, @FuelListBoxValueChanged, true);
            app.TypeListBoxLabel = uilabel(app.TransportTab);
            app.TypeListBoxLabel.Text = 'Type';
            app.TypeListBox = uilistbox(app.TransportTab);
            app.TypeListBox.ValueChangedFcn = createCallbackFcn(app, @TypeListBoxValueChanged, true);
            app.SizeListBoxLabel = uilabel(app.TransportTab);
            app.SizeListBoxLabel.Text = 'Size';
            app.SizeListBox = uilistbox(app.TransportTab);
            app.SizeListBox.ValueChangedFcn = createCallbackFcn(app, @SizeListBoxValueChanged, true);
            app.TransportListBox = uilistbox(app.TransportTab);
            app.TransportListBox.ValueChangedFcn = createCallbackFcn(app, @TransportListBoxValueChanged, true);
            app.DairyfarmLabel = uilabel(app.TransportTab);
            app.DairyfarmLabel.Text = {'Dairy'; 'farm'};
            app.SugarBeetfarmLabel = uilabel(app.TransportTab);
            app.SugarBeetfarmLabel.Text = {'Sugar Beet'; 'farm'};
            app.StrawberryfarmLabel = uilabel(app.TransportTab);
            app.StrawberryfarmLabel.Text = {'Strawberry'; 'farm'};
            app.FactoryLabel = uilabel(app.TransportTab);
            app.FactoryLabel.Text = 'Factory';
            app.ShopLabel = uilabel(app.TransportTab);
            app.ShopLabel.Text = 'Shop';
            app.dist_cow_fact = uieditfield(app.TransportTab, 'numeric');
            app.dist_cow_fact.ValueChangedFcn = createCallbackFcn(app, @dist_cow_factValueChanged, true);
            app.dist_beet_fact = uieditfield(app.TransportTab, 'numeric');
            app.dist_beet_fact.ValueChangedFcn = createCallbackFcn(app, @dist_beet_factValueChanged, true);
            app.dist_stra_fact = uieditfield(app.TransportTab, 'numeric');
            app.dist_stra_fact.ValueChangedFcn = createCallbackFcn(app, @dist_stra_factValueChanged, true);
            app.dist_fact_shop = uieditfield(app.TransportTab, 'numeric');
            app.dist_fact_shop.ValueChangedFcn = createCallbackFcn(app, @dist_fact_shopValueChanged, true);
            app.kmLabel = uilabel(app.TransportTab);
            app.kmLabel.Text = 'km';
            app.kmLabel_2 = uilabel(app.TransportTab);
            app.kmLabel_2.Text = 'km';
            app.kmLabel_3 = uilabel(app.TransportTab);
            app.kmLabel_3.Text = 'km';
            app.kmLabel_4 = uilabel(app.TransportTab);
            app.kmLabel_4.Text = 'km';
            app.transport_EditmodeButtonGroup = uibuttongroup(app.TransportTab);
            app.transport_EditmodeButtonGroup.Title = 'Edit mode:';
            app.trans_ind_btn = uiradiobutton(app.transport_EditmodeButtonGroup);
            app.trans_ind_btn.Text = 'Individual';
            app.trans_ind_btn.Value = true;
            app.trans_all_btn = uiradiobutton(app.transport_EditmodeButtonGroup);
            app.trans_all_btn.Text = 'All';
            app.SelectedtripLabel = uilabel(app.TransportTab);
            app.SelectedtripLabel.Text = 'Selected trip:';
            app.TransportmapLabel = uilabel(app.TransportTab);
            app.TransportmapLabel.Text = 'Transport map:';
            app.Label = uilabel(app.TransportTab);
            app.Label.Text = '...';
            app.CapacitytonsLabel = uilabel(app.TransportTab);
            app.CapacitytonsLabel.Text = 'Capacity (tons):';
            app.Label_2 = uilabel(app.TransportTab);
            app.Label_2.Text = '20';
            app.Label_3 = uilabel(app.TransportTab);
            app.Label_3.Text = '2';
            app.Label_4 = uilabel(app.TransportTab);
            app.Label_4.Text = '0.2';
            app.Image_hgv = uiimage(app.TransportTab);
            app.Image_hgv.Enable = 'off';
            app.Image_hgv.ImageSource = fullfile(pathToMLAPP, 'res', 'vehicle_hgv.svg');
            app.Image_van = uiimage(app.TransportTab);
            app.Image_van.ImageSource = fullfile(pathToMLAPP, 'res', 'vehicle_van.svg');
            app.Image_car = uiimage(app.TransportTab);
            app.Image_car.Enable = 'off';
            app.Image_car.ImageSource = fullfile(pathToMLAPP, 'res', 'vehicle_car.svg');
            app.CompleteprocessloadmultiplierEditFieldLabel = uilabel(app.TransportTab);
            app.CompleteprocessloadmultiplierEditFieldLabel.Text = 'Complete process load multiplier:';
            app.processloadmultiplierEditField = uieditfield(app.TransportTab, 'numeric');
            app.processloadmultiplierEditField.Limits = [1 1000000];
            app.processloadmultiplierEditField.ValueDisplayFormat = '%.0f';
            app.processloadmultiplierEditField.ValueChangedFcn = createCallbackFcn(app, @processloadmultiplierEditFieldValueChanged, true);
            app.processloadmultiplierEditField.Value = 1;
            app.Label_6 = uilabel(app.TransportTab);
            app.Label_6.Text = '↓';
            app.Label_8 = uilabel(app.TransportTab);
            app.Label_8.Text = '↓';
            app.TimingTab = uitab(app.TabGroup);
            app.TimingTab.Title = 'Timing';
            app.RawmilkSliderLabel = uilabel(app.TimingTab);
            app.RawmilkSliderLabel.Text = {'Raw'; 'milk'};
            app.RawmilkSlider = uislider(app.TimingTab);
            app.RawmilkSlider.Limits = [0 24];
            app.RawmilkSlider.ValueChangedFcn = createCallbackFcn(app, @RawmilkSliderValueChanged, true);
            app.RawmilkSlider.Enable = 'off';
            app.RawmilkSlider.Value = 9;
            app.YoghurtSliderLabel = uilabel(app.TimingTab);
            app.YoghurtSliderLabel.Text = 'Yoghurt';
            app.YoghurtSlider = uislider(app.TimingTab);
            app.YoghurtSlider.Limits = [0 24];
            app.YoghurtSlider.ValueChangedFcn = createCallbackFcn(app, @YoghurtSliderValueChanged, true);
            app.YoghurtSlider.Enable = 'off';
            app.YoghurtSlider.Value = 9;
            app.SugarbeetSliderLabel = uilabel(app.TimingTab);
            app.SugarbeetSliderLabel.Text = {'Sugar'; 'beet'};
            app.SugarbeetSlider = uislider(app.TimingTab);
            app.SugarbeetSlider.Limits = [0 24];
            app.SugarbeetSlider.ValueChangedFcn = createCallbackFcn(app, @SugarbeetSliderValueChanged, true);
            app.SugarbeetSlider.Enable = 'off';
            app.SugarbeetSlider.Value = 9;
            app.SugarSliderLabel = uilabel(app.TimingTab);
            app.SugarSliderLabel.Text = 'Sugar';
            app.SugarSlider = uislider(app.TimingTab);
            app.SugarSlider.Limits = [0 24];
            app.SugarSlider.ValueChangedFcn = createCallbackFcn(app, @SugarSliderValueChanged, true);
            app.SugarSlider.Enable = 'off';
            app.SugarSlider.Value = 9;
            app.StrawberriesSliderLabel = uilabel(app.TimingTab);
            app.StrawberriesSliderLabel.Text = 'Strawberries';
            app.StrawberriesSlider = uislider(app.TimingTab);
            app.StrawberriesSlider.Limits = [0 24];
            app.StrawberriesSlider.ValueChangedFcn = createCallbackFcn(app, @StrawberriesSliderValueChanged, true);
            app.StrawberriesSlider.Enable = 'off';
            app.StrawberriesSlider.Value = 9;
            app.FlavouredyoghurtSlider_6Label = uilabel(app.TimingTab);
            app.FlavouredyoghurtSlider_6Label.Text = {'Flavoured'; 'yoghurt'};
            app.FlavouredyoghurtSlider = uislider(app.TimingTab);
            app.FlavouredyoghurtSlider.Limits = [0 24];
            app.FlavouredyoghurtSlider.ValueChangedFcn = createCallbackFcn(app, @FlavouredyoghurtSliderValueChanged, true);
            app.FlavouredyoghurtSlider.Enable = 'off';
            app.FlavouredyoghurtSlider.Value = 9;
            app.JamSliderLabel = uilabel(app.TimingTab);
            app.JamSliderLabel.Text = 'Jam';
            app.JamSlider = uislider(app.TimingTab);
            app.JamSlider.Limits = [0 24];
            app.JamSlider.ValueChangedFcn = createCallbackFcn(app, @JamSliderValueChanged, true);
            app.JamSlider.Enable = 'off';
            app.JamSlider.Value = 9;
            app.Processstarttimes24hrLabel = uilabel(app.TimingTab);
            app.Processstarttimes24hrLabel.Text = 'Process start times (24hr)';
            app.BETALabel_2 = uilabel(app.TimingTab);
            app.BETALabel_2.Text = '(BETA)';
            app.MiscTab = uitab(app.TabGroup);
            app.MiscTab.Title = 'Misc';
            app.final_product_numericalEditField_2 = uieditfield(app.MiscTab, 'numeric');
            app.final_product_numericalEditField_2.ValueChangedFcn = createCallbackFcn(app, @final_product_numericalEditField_2ValueChanged, true);
            app.final_product_numericalEditField_2.Visible = 'off';
            app.final_product_numericalEditField_2.Value = 1;
            app.FinalproductweighttonnesLabel = uilabel(app.MiscTab);
            app.FinalproductweighttonnesLabel.Visible = 'off';
            app.FinalproductweighttonnesLabel.Text = {'Final product'; 'weight, tonnes'};
            app.final_product_Slider = uislider(app.MiscTab);
            app.final_product_Slider.Limits = [0.001 100];
            app.final_product_Slider.MajorTicks = [1 50 100];
            app.final_product_Slider.MajorTickLabels = {'1', '50', '100'};
            app.final_product_Slider.ValueChangedFcn = createCallbackFcn(app, @final_product_SliderValueChanged, true);
            app.final_product_Slider.Visible = 'off';
            app.final_product_Slider.Value = 1;
            app.ControlsLabel = uilabel(app.LeftPanel);
            app.ControlsLabel.Text = 'Controls';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.CenterPanel);
            app.SummaryTab = uitab(app.TabGroup2);
            app.SummaryTab.Title = 'Summary';
            app.fig8 = uiaxes(app.SummaryTab);
            title(app.fig8, 'Fig 8. Energy balance for each step')
            zlabel(app.fig8, 'Z')
            app.fig8.XTick = [];
            app.fig8.XTickLabel = '';
            app.fig9 = uiaxes(app.SummaryTab);
            title(app.fig9, 'Fig 9. Comparison of total & specific energy consumption')
            zlabel(app.fig9, 'Z')
            app.fig9.XTick = [];
            app.fig9.XTickLabel = '';
            app.fig10 = uiaxes(app.SummaryTab);
            title(app.fig10, 'Fig 10. Exergy of input, fuels, main product, and exergy loss')
            zlabel(app.fig10, 'Z')
            app.fig10.XTick = [];
            app.fig10.XTickLabel = '';
            app.fig11 = uiaxes(app.SummaryTab);
            title(app.fig11, 'Fig 11. Comparison of total & specific exergy consumption')
            zlabel(app.fig11, 'Z')
            app.fig11.XTick = [];
            app.fig11.XTickLabel = '';
            app.fig12 = uiaxes(app.SummaryTab);
            title(app.fig12, 'Fig 12.  Comparison of total & specific CO2 emissions')
            zlabel(app.fig12, 'Z')
            app.fig12.XTick = [];
            app.fig12.XTickLabel = '';
            app.Label2 = uilabel(app.SummaryTab);
            app.Label2.Text = {'A = Skim milk production, B = Milk powder production, C = Plain yoghurt making,'; 'D = Packaging & transportation, E = Strawberry production, F = Sugar beet production'};
            app.EnergyExergybyprocessTab = uitab(app.TabGroup2);
            app.EnergyExergybyprocessTab.Title = 'Energy/Exergy by process';
            app.UIAxes_exergy = uiaxes(app.EnergyExergybyprocessTab);
            ylabel(app.UIAxes_exergy, 'Exergy, MJ')
            zlabel(app.UIAxes_exergy, 'Z')
            app.UIAxes_energy = uiaxes(app.EnergyExergybyprocessTab);
            ylabel(app.UIAxes_energy, 'Energy, MJ')
            zlabel(app.UIAxes_energy, 'Z')
            app.UIAxes_co2 = uiaxes(app.EnergyExergybyprocessTab);
            ylabel(app.UIAxes_co2, 'CO2, kg')
            zlabel(app.UIAxes_co2, 'Z')
            app.UIAxes_legend1 = uiaxes(app.EnergyExergybyprocessTab);
            title(app.UIAxes_legend1, 'Legend:')
            app.UIAxes_legend1.XTick = [];
            app.UIAxes_legend1.YTick = [];
            app.ProcessDropDown = uilistbox(app.EnergyExergybyprocessTab);
            app.ProcessDropDown.ValueChangedFcn = createCallbackFcn(app, @ProcessDropDownValueChanged, true);
            app.ProcessLabel = uilabel(app.EnergyExergybyprocessTab);
            app.ProcessLabel.Text = 'Process:';
            app.BLUEisthesimulationLabel = uilabel(app.EnergyExergybyprocessTab);
            app.BLUEisthesimulationLabel.Text = 'BLUE is the result of the simulation using the current values inputted in controls';
            app.BLUEisthesimulationLabel_2 = uilabel(app.EnergyExergybyprocessTab);
            app.BLUEisthesimulationLabel_2.Text = 'RED is the baseline simulation from the paper OR the simulation from last time HOLD was pressed';
            app.TransportTab_2 = uitab(app.TabGroup2);
            app.TransportTab_2.Title = 'Transport';
            app.UIAxes_t_load = uiaxes(app.TransportTab_2);
            title(app.UIAxes_t_load, 'Load')
            ylabel(app.UIAxes_t_load, 'kg')
            zlabel(app.UIAxes_t_load, 'Z')
            app.UIAxes_t_CO2 = uiaxes(app.TransportTab_2);
            title(app.UIAxes_t_CO2, 'CO2')
            ylabel(app.UIAxes_t_CO2, 'kg')
            zlabel(app.UIAxes_t_CO2, 'Z')
            app.UIAxes_t_CEnC = uiaxes(app.TransportTab_2);
            title(app.UIAxes_t_CEnC, 'CEnC')
            ylabel(app.UIAxes_t_CEnC, 'MJ')
            zlabel(app.UIAxes_t_CEnC, 'Z')
            app.UIAxes_t_CExC = uiaxes(app.TransportTab_2);
            title(app.UIAxes_t_CExC, 'CExC')
            ylabel(app.UIAxes_t_CExC, 'MJ')
            zlabel(app.UIAxes_t_CExC, 'Z')
            app.UIAxes_t_load_2 = uiaxes(app.TransportTab_2);
            title(app.UIAxes_t_load_2, 'Load')
            ylabel(app.UIAxes_t_load_2, 'kg')
            zlabel(app.UIAxes_t_load_2, 'Z')
            app.UIAxes_t_CO2_2 = uiaxes(app.TransportTab_2);
            title(app.UIAxes_t_CO2_2, 'CO2')
            ylabel(app.UIAxes_t_CO2_2, 'kg')
            zlabel(app.UIAxes_t_CO2_2, 'Z')
            app.UIAxes_t_CEnC_2 = uiaxes(app.TransportTab_2);
            title(app.UIAxes_t_CEnC_2, 'CEnC')
            ylabel(app.UIAxes_t_CEnC_2, 'MJ')
            zlabel(app.UIAxes_t_CEnC_2, 'Z')
            app.UIAxes_t_CExC_2 = uiaxes(app.TransportTab_2);
            title(app.UIAxes_t_CExC_2, 'CExC')
            ylabel(app.UIAxes_t_CExC_2, 'MJ')
            zlabel(app.UIAxes_t_CExC_2, 'Z')
            app.UIAxes_legend1_2 = uiaxes(app.TransportTab_2);
            title(app.UIAxes_legend1_2, 'Legend:')
            app.UIAxes_legend1_2.XTick = [];
            app.UIAxes_legend1_2.YTick = [];
            app.BLUEisthesimulationLabel_4 = uilabel(app.TransportTab_2);
            app.BLUEisthesimulationLabel_4.Text = 'RED is the baseline simulation from the paper OR the simulation from last time HOLD was pressed';
            app.BLUEisthesimulationLabel_3 = uilabel(app.TransportTab_2);
            app.BLUEisthesimulationLabel_3.Text = 'BLUE is the result of the simulation using the current values inputted in controls';
            app.SelectedtripLabel_2 = uilabel(app.TransportTab_2);
            app.SelectedtripLabel_2.Text = 'Selected trip:';
            app.TotalsLabel = uilabel(app.TransportTab_2);
            app.TotalsLabel.Text = 'Totals:';
            app.JourneysLabel = uilabel(app.TransportTab_2);
            app.JourneysLabel.Text = 'Number of journeys';
            app.NumberofjourneysEditField = uieditfield(app.TransportTab_2, 'numeric');
            app.FuelconsumptionEditFieldLabel = uilabel(app.TransportTab_2);
            app.FuelconsumptionEditFieldLabel.Text = 'Fuel consumption';
            app.FuelconsumptionEditField = uieditfield(app.TransportTab_2, 'numeric');
            app.NumberofjourneysEditField_2Label = uilabel(app.TransportTab_2);
            app.NumberofjourneysEditField_2Label.Text = 'Number of journeys';
            app.Numberofjourneys_total_EditField = uieditfield(app.TransportTab_2, 'numeric');
            app.FuelconsumptionEditField_2Label = uilabel(app.TransportTab_2);
            app.FuelconsumptionEditField_2Label.Text = 'Fuel consumption';
            app.Fuelconsumption_total_EditField = uieditfield(app.TransportTab_2, 'numeric');
            app.ProcessdiagramsTab = uitab(app.TabGroup2);
            app.ProcessdiagramsTab.Title = 'Process diagrams';
            app.PDAxes = uiaxes(app.ProcessdiagramsTab);
            app.PDAxes.XTick = [];
            app.PDAxes.XTickLabel = '';
            app.PDAxes.YTick = [];
            app.PDAxes.YTickLabel = '';
            app.PDAxes.ZTick = [];
            app.PDimage = uiimage(app.ProcessdiagramsTab);
            app.PDimage.ImageSource = fullfile(pathToMLAPP, 'res', 'PD_yoghurt.png');
            app.Y_1 = uilabel(app.ProcessdiagramsTab);
            app.Y_1.Visible = 'off';
            app.Y_1.Text = 'Yoghurt';
            app.Y_2 = uilabel(app.ProcessdiagramsTab);
            app.Y_2.Visible = 'off';
            app.Y_2.Text = 'Yoghurt';
            app.proc_Y_fat_separation = uilabel(app.ProcessdiagramsTab);
            app.proc_Y_fat_separation.Visible = 'off';
            app.proc_Y_fat_separation.Text = 'Fat Separation';
            app.Y_4 = uilabel(app.ProcessdiagramsTab);
            app.Y_4.Visible = 'off';
            app.Y_4.Text = 'Yoghurt';
            app.Y_5 = uilabel(app.ProcessdiagramsTab);
            app.Y_5.Visible = 'off';
            app.Y_5.Text = 'Yoghurt';
            app.Y_6 = uilabel(app.ProcessdiagramsTab);
            app.Y_6.Visible = 'off';
            app.Y_6.Text = 'Yoghurt';
            app.proc_Y_spray_drying = uilabel(app.ProcessdiagramsTab);
            app.proc_Y_spray_drying.Visible = 'off';
            app.proc_Y_spray_drying.Text = 'Spray Drying';
            app.Y_8 = uilabel(app.ProcessdiagramsTab);
            app.Y_8.Visible = 'off';
            app.Y_8.Text = 'Yoghurt';
            app.Y_9 = uilabel(app.ProcessdiagramsTab);
            app.Y_9.Visible = 'off';
            app.Y_9.Text = 'Yoghurt';
            app.proc_Y_production = uilabel(app.ProcessdiagramsTab);
            app.proc_Y_production.Visible = 'off';
            app.proc_Y_production.Text = 'Yoghurt Production';
            app.Y_11 = uilabel(app.ProcessdiagramsTab);
            app.Y_11.Visible = 'off';
            app.Y_11.Text = 'Yoghurt';
            app.proc_Y_microbial = uilabel(app.ProcessdiagramsTab);
            app.proc_Y_microbial.Visible = 'off';
            app.proc_Y_microbial.Text = 'Microbial Culture Production';
            app.Y_13 = uilabel(app.ProcessdiagramsTab);
            app.Y_13.Visible = 'off';
            app.Y_13.Text = 'Yoghurt';
            app.Y_14 = uilabel(app.ProcessdiagramsTab);
            app.Y_14.Visible = 'off';
            app.Y_14.Text = 'Yoghurt';
            app.Y_15 = uilabel(app.ProcessdiagramsTab);
            app.Y_15.Visible = 'off';
            app.Y_15.Text = 'Yoghurt';
            app.Y_16 = uilabel(app.ProcessdiagramsTab);
            app.Y_16.Visible = 'off';
            app.Y_16.Text = 'Yoghurt';
            app.S_1 = uilabel(app.ProcessdiagramsTab);
            app.S_1.Visible = 'off';
            app.S_1.Text = 'Sugar';
            app.S_2 = uilabel(app.ProcessdiagramsTab);
            app.S_2.Visible = 'off';
            app.S_2.Text = 'Sugar';
            app.S_3 = uilabel(app.ProcessdiagramsTab);
            app.S_3.Visible = 'off';
            app.S_3.Text = 'Sugar';
            app.S_4 = uilabel(app.ProcessdiagramsTab);
            app.S_4.Visible = 'off';
            app.S_4.Text = 'Sugar';
            app.proc_S_sugar_beet_production = uilabel(app.ProcessdiagramsTab);
            app.proc_S_sugar_beet_production.Visible = 'off';
            app.proc_S_sugar_beet_production.Text = {'Sugar Beet'; 'Production'};
            app.S_6 = uilabel(app.ProcessdiagramsTab);
            app.S_6.Visible = 'off';
            app.S_6.Text = 'Organic Manure';
            app.S_7 = uilabel(app.ProcessdiagramsTab);
            app.S_7.Visible = 'off';
            app.S_7.Text = 'Sugar';
            app.S_8 = uilabel(app.ProcessdiagramsTab);
            app.S_8.Visible = 'off';
            app.S_8.Text = 'Sugar';
            app.proc_S_cleaning_sorting = uilabel(app.ProcessdiagramsTab);
            app.proc_S_cleaning_sorting.Visible = 'off';
            app.proc_S_cleaning_sorting.Text = {'Cleaning and'; 'Sorting'};
            app.S_10 = uilabel(app.ProcessdiagramsTab);
            app.S_10.Visible = 'off';
            app.S_10.Text = 'Sugar';
            app.S_11 = uilabel(app.ProcessdiagramsTab);
            app.S_11.Visible = 'off';
            app.S_11.Text = 'Sugar';
            app.S_12 = uilabel(app.ProcessdiagramsTab);
            app.S_12.Visible = 'off';
            app.S_12.Text = 'Sugar';
            app.proc_SB_recycling = uilabel(app.ProcessdiagramsTab);
            app.proc_SB_recycling.Visible = 'off';
            app.proc_SB_recycling.Text = 'Recycling';
            app.S_14 = uilabel(app.ProcessdiagramsTab);
            app.S_14.Visible = 'off';
            app.S_14.Text = 'Sugar';
            app.S_15 = uilabel(app.ProcessdiagramsTab);
            app.S_15.Visible = 'off';
            app.S_15.Text = 'Sugar';
            app.S_16 = uilabel(app.ProcessdiagramsTab);
            app.S_16.Visible = 'off';
            app.S_16.Text = 'Sugar';
            app.proc_S_sugar_production = uilabel(app.ProcessdiagramsTab);
            app.proc_S_sugar_production.Visible = 'off';
            app.proc_S_sugar_production.Text = 'Production of Sugar';
            app.S_18 = uilabel(app.ProcessdiagramsTab);
            app.S_18.Visible = 'off';
            app.S_18.Text = 'Sugar';
            app.proc_S_packaging = uilabel(app.ProcessdiagramsTab);
            app.proc_S_packaging.Visible = 'off';
            app.proc_S_packaging.Text = 'Packaging';
            app.S_20 = uilabel(app.ProcessdiagramsTab);
            app.S_20.Visible = 'off';
            app.S_20.Text = 'Sugar';
            app.S_21 = uilabel(app.ProcessdiagramsTab);
            app.S_21.Visible = 'off';
            app.S_21.Text = 'Sugar';
            app.S_22 = uilabel(app.ProcessdiagramsTab);
            app.S_22.Visible = 'off';
            app.S_22.Text = 'Sugar';
            app.SJ_1 = uilabel(app.ProcessdiagramsTab);
            app.SJ_1.Visible = 'off';
            app.SJ_1.Text = 'S_Jam';
            app.SJ_2 = uilabel(app.ProcessdiagramsTab);
            app.SJ_2.Visible = 'off';
            app.SJ_2.Text = 'S_Jam';
            app.SJ_3 = uilabel(app.ProcessdiagramsTab);
            app.SJ_3.Visible = 'off';
            app.SJ_3.Text = 'S_Jam';
            app.proc_SJ_jam_making = uilabel(app.ProcessdiagramsTab);
            app.proc_SJ_jam_making.Visible = 'off';
            app.proc_SJ_jam_making.Text = 'Jam Making';
            app.SJ_5 = uilabel(app.ProcessdiagramsTab);
            app.SJ_5.Visible = 'off';
            app.SJ_5.Text = 'S_Jam';
            app.SJ_6 = uilabel(app.ProcessdiagramsTab);
            app.SJ_6.Visible = 'off';
            app.SJ_6.Text = 'S_Jam';
            app.SJ_7 = uilabel(app.ProcessdiagramsTab);
            app.SJ_7.Visible = 'off';
            app.SJ_7.Text = 'S_Jam';
            app.SJ_8 = uilabel(app.ProcessdiagramsTab);
            app.SJ_8.Visible = 'off';
            app.SJ_8.Text = 'S_Jam';
            app.SB_1 = uilabel(app.ProcessdiagramsTab);
            app.SB_1.Visible = 'off';
            app.SB_1.Text = 'SB';
            app.SB_2 = uilabel(app.ProcessdiagramsTab);
            app.SB_2.Visible = 'off';
            app.SB_2.Text = 'SB';
            app.SB_3 = uilabel(app.ProcessdiagramsTab);
            app.SB_3.Visible = 'off';
            app.SB_3.Text = 'SB';
            app.SB_4 = uilabel(app.ProcessdiagramsTab);
            app.SB_4.Visible = 'off';
            app.SB_4.Text = 'SB';
            app.SB_5x = uilabel(app.ProcessdiagramsTab);
            app.SB_5x.Visible = 'off';
            app.SB_5x.Text = 'SB';
            app.proc_SB_production = uilabel(app.ProcessdiagramsTab);
            app.proc_SB_production.Visible = 'off';
            app.proc_SB_production.Text = 'Strawberry Production';
            app.SB_7 = uilabel(app.ProcessdiagramsTab);
            app.SB_7.Visible = 'off';
            app.SB_7.Text = 'SB';
            app.proc_S_recycling = uilabel(app.ProcessdiagramsTab);
            app.proc_S_recycling.Visible = 'off';
            app.proc_S_recycling.Text = 'Recycling';
            app.SB_9 = uilabel(app.ProcessdiagramsTab);
            app.SB_9.Visible = 'off';
            app.SB_9.Text = 'SB';
            app.SB_10 = uilabel(app.ProcessdiagramsTab);
            app.SB_10.Visible = 'off';
            app.SB_10.Text = 'SB';
            app.SB_11 = uilabel(app.ProcessdiagramsTab);
            app.SB_11.Visible = 'off';
            app.SB_11.Text = 'SB';
            app.proc_SB_clean_sort = uilabel(app.ProcessdiagramsTab);
            app.proc_SB_clean_sort.Visible = 'off';
            app.proc_SB_clean_sort.Text = 'Cleaning and Sorting';
            app.SB_13 = uilabel(app.ProcessdiagramsTab);
            app.SB_13.Visible = 'off';
            app.SB_13.Text = 'SB';
            app.SB_14 = uilabel(app.ProcessdiagramsTab);
            app.SB_14.Visible = 'off';
            app.SB_14.Text = 'SB';
            app.SB_15 = uilabel(app.ProcessdiagramsTab);
            app.SB_15.Visible = 'off';
            app.SB_15.Text = 'SB';
            app.SB_16 = uilabel(app.ProcessdiagramsTab);
            app.SB_16.Visible = 'off';
            app.SB_16.Text = 'SB';
            app.M_1 = uilabel(app.ProcessdiagramsTab);
            app.M_1.Visible = 'off';
            app.M_1.Text = 'Milk';
            app.M_2 = uilabel(app.ProcessdiagramsTab);
            app.M_2.Visible = 'off';
            app.M_2.Text = 'Milk';
            app.proc_M_production = uilabel(app.ProcessdiagramsTab);
            app.proc_M_production.Visible = 'off';
            app.proc_M_production.Text = {'Raw Milk'; 'Production'};
            app.M_4 = uilabel(app.ProcessdiagramsTab);
            app.M_4.Visible = 'off';
            app.M_4.Text = 'Milk';
            app.M_5 = uilabel(app.ProcessdiagramsTab);
            app.M_5.Visible = 'off';
            app.M_5.Text = 'Milk';
            app.M_6x = uilabel(app.ProcessdiagramsTab);
            app.M_6x.Visible = 'off';
            app.M_6x.Text = 'Organic Manure';
            app.FY_1 = uilabel(app.ProcessdiagramsTab);
            app.FY_1.Visible = 'off';
            app.FY_1.Text = 'FY';
            app.FY_2 = uilabel(app.ProcessdiagramsTab);
            app.FY_2.Visible = 'off';
            app.FY_2.Text = 'FY';
            app.proc_FY_making = uilabel(app.ProcessdiagramsTab);
            app.proc_FY_making.Visible = 'off';
            app.proc_FY_making.Text = {'Flavoured Yoghurt'; 'Making'};
            app.FY_4 = uilabel(app.ProcessdiagramsTab);
            app.FY_4.Visible = 'off';
            app.FY_4.Text = 'FY';
            app.FY_5 = uilabel(app.ProcessdiagramsTab);
            app.FY_5.Visible = 'off';
            app.FY_5.Text = 'FY';
            app.proc_FY_packaging = uilabel(app.ProcessdiagramsTab);
            app.proc_FY_packaging.Visible = 'off';
            app.proc_FY_packaging.Text = 'Packaging';
            app.FY_7x = uilabel(app.ProcessdiagramsTab);
            app.FY_7x.Visible = 'off';
            app.FY_7x.Text = 'Refrigerated Transport';
            app.FY_8 = uilabel(app.ProcessdiagramsTab);
            app.FY_8.Visible = 'off';
            app.FY_8.Text = 'FY';
            app.FY_9 = uilabel(app.ProcessdiagramsTab);
            app.FY_9.Visible = 'off';
            app.FY_9.Text = 'FY';
            app.ProcessListBox = uilistbox(app.ProcessdiagramsTab);
            app.ProcessListBox.Items = {'Milk', 'Yoghurt', 'Strawberry', 'Sugar', 'Strawberry Jam', 'Flavoured Yoghurt'};
            app.ProcessListBox.ValueChangedFcn = createCallbackFcn(app, @ProcessListBoxValueChanged, true);
            app.ProcessListBox.Value = 'Milk';
            app.ProcesstimingTab_2 = uitab(app.TabGroup2);
            app.ProcesstimingTab_2.Title = 'Process timing';
            app.TimingAxes = uiaxes(app.ProcesstimingTab_2);
            xlabel(app.TimingAxes, 'Time, hours')
            zlabel(app.TimingAxes, 'Z')
            app.TimingAxes.YTick = [];
            app.TimingAxes.YTickLabel = '';
            app.BETALabel = uilabel(app.ProcesstimingTab_2);
            app.BETALabel.Text = '(BETA)';
            app.ResultsLabel = uilabel(app.CenterPanel);
            app.ResultsLabel.Text = 'Results';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'res', 'logo.svg');
            app.EXITButton = uibutton(app.RightPanel, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.DifficultyButtonGroup = uibuttongroup(app.RightPanel);
            app.DifficultyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DifficultyButtonGroupSelectionChanged, true);
            app.DifficultyButtonGroup.Title = 'Difficulty';
            app.Level1Button = uiradiobutton(app.DifficultyButtonGroup);
            app.Level1Button.Text = 'Level 1';
            app.Level2Button = uiradiobutton(app.DifficultyButtonGroup);
            app.Level2Button.Enable = 'off';
            app.Level2Button.Text = 'Level 2';
            app.Level2Button.Value = true;
            app.Level3Button = uiradiobutton(app.DifficultyButtonGroup);
            app.Level3Button.Enable = 'off';
            app.Level3Button.Text = 'Level 3';
            app.V16Label = uilabel(app.RightPanel);
            app.V16Label.Text = 'V1.6';
            app.ResetTransportButton = uibutton(app.RightPanel, 'push');
            app.ResetTransportButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTransportButtonPushed, true);
            app.ResetTransportButton.Text = 'Reset Transport';
            app.ResetMachinesButton = uibutton(app.RightPanel, 'push');
            app.ResetMachinesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMachinesButtonPushed, true);
            app.ResetMachinesButton.Text = 'Reset Machines';
            app.ResetElectricityButton = uibutton(app.RightPanel, 'push');
            app.ResetElectricityButton.ButtonPushedFcn = createCallbackFcn(app, @ResetElectricityButtonPushed, true);
            app.ResetElectricityButton.Text = 'Reset Electricity';
            app.ResetFinalProductButton = uibutton(app.RightPanel, 'push');
            app.ResetFinalProductButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFinalProductButtonPushed, true);
            app.ResetFinalProductButton.Text = 'Reset Final Product';
            app.ResetProcesstimingButton = uibutton(app.RightPanel, 'push');
            app.ResetProcesstimingButton.ButtonPushedFcn = createCallbackFcn(app, @ResetProcesstimingButtonPushed, true);
            app.ResetProcesstimingButton.Text = 'Reset Process timing';
            app.ResetHOLDButton = uibutton(app.RightPanel, 'push');
            app.ResetHOLDButton.ButtonPushedFcn = createCallbackFcn(app, @ResetHOLDButtonPushed, true);
            app.ResetHOLDButton.Text = 'Reset HOLD';
            app.HOLDButton = uibutton(app.RightPanel, 'push');
            app.HOLDButton.ButtonPushedFcn = createCallbackFcn(app, @HOLDButtonPushed, true);
            app.HOLDButton.Text = 'HOLD';
            app.CO2ekgLabel = uilabel(app.RightPanel);
            app.CO2ekgLabel.Text = 'CO2e (kg)';
            app.score_CO2eEditField = uieditfield(app.RightPanel, 'numeric');
            app.score_CO2eEditField.ValueDisplayFormat = '%.0f';
            app.score_CDP_hold = uilabel(app.RightPanel);
            app.score_CDP_hold.Text = 'base value';
            app.score_machine_hold = uilabel(app.RightPanel);
            app.score_machine_hold.Text = 'base value';
            app.score_CO2e_hold = uilabel(app.RightPanel);
            app.score_CO2e_hold.Text = 'base value';
            app.OUTPUTSCOREButton = uibutton(app.RightPanel, 'push');
            app.OUTPUTSCOREButton.ButtonPushedFcn = createCallbackFcn(app, @OUTPUTSCOREButtonPushed, true);
            app.OUTPUTSCOREButton.Text = 'OUTPUT SCORE';
            app.CDPLabel = uilabel(app.RightPanel);
            app.CDPLabel.Text = 'CDP, %';
            app.score_CDPEditField = uieditfield(app.RightPanel, 'numeric');
            app.MachinecostLabel = uilabel(app.RightPanel);
            app.MachinecostLabel.Text = 'Machine cost, £';
            app.score_MachinecostEditField = uieditfield(app.RightPanel, 'numeric');
            app.score_MachinecostEditField.ValueDisplayFormat = '%.0f';
            % Show the figure after all components are created
            app.H7136SustainabilityinengineeringappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sie_app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.H7136SustainabilityinengineeringappUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.H7136SustainabilityinengineeringappUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.H7136SustainabilityinengineeringappUIFigure)
        end
    end
end